classdef ConfigureSegmentationGUI_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ImageAxes                       matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        PreviewsegmentationButton       matlab.ui.control.Button
        UpdateparamsButton              matlab.ui.control.Button
        ComputeROIsButton               matlab.ui.control.Button
        Compute7thlayerButton           matlab.ui.control.Button
        DrusenCheckBox                  matlab.ui.control.CheckBox
        SaveconfigurationButton         matlab.ui.control.Button
        RegionsofinterestsPanel         matlab.ui.container.Panel
        EightButton                     matlab.ui.control.StateButton
        SeventhButton                   matlab.ui.control.StateButton
        SixthButton                     matlab.ui.control.StateButton
        fifthButton                     matlab.ui.control.StateButton
        fourthButton                    matlab.ui.control.StateButton
        thirdButton                     matlab.ui.control.StateButton
        secondButton                    matlab.ui.control.StateButton
        firstButton                     matlab.ui.control.StateButton
        ChoosewhichROIstodisplayLabel   matlab.ui.control.Label
        ISOS7thlayerPanel               matlab.ui.container.Panel
        GradientSmoothnessSpinner       matlab.ui.control.Spinner
        GradientSmoothnessSpinnerLabel  matlab.ui.control.Label
        GradientEnhancementSpinner      matlab.ui.control.Spinner
        GradientEnhancementSpinnerLabel  matlab.ui.control.Label
        EnhancementSpinner              matlab.ui.control.Spinner
        EnhancementSpinnerLabel         matlab.ui.control.Label
        SmoothnessSpinner               matlab.ui.control.Spinner
        SmoothnessSpinnerLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        fileName % Description
        params % Description
        layers,
        ROI,
        l7thLayer,
        computed7thLayer,
        computedROIs,
        computedLayers,
        layersLoaded,
        isDrusen,
        ROIStatus
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            end
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            
            
        end
        
        function [path,name,ext] = extractInfoFromPath(app, fileName)
            layersFolder = split(fileName,'\');
            [m,~] = size(layersFolder);
            fileName = char(layersFolder(m));
            layersFolder = char(join(layersFolder(1:m-1)));
            layersFolder = strrep(layersFolder, ' ','\');
            layersFolder = [layersFolder '\'];
            fileName = split(fileName, '.');
            ext = ['.' char(fileName(2))];
            fileName = char(fileName(1));
            
            path = layersFolder;
            name = fileName;
            
        end
        
        function reloadLayers(app)
            disp(app.layers);
            
            if app.computedLayers == true
                if app.layersLoaded == true
                    set(app.ImageAxes.Children(1), 'Ydata', app.layers.B1);
                    set(app.ImageAxes.Children(2), 'Ydata', app.layers.B2);
                    set(app.ImageAxes.Children(3), 'Ydata', app.layers.B3);
                    set(app.ImageAxes.Children(4), 'Ydata', app.layers.B4);
                    set(app.ImageAxes.Children(5), 'Ydata', app.layers.B5);
                    set(app.ImageAxes.Children(6), 'Ydata', app.layers.B6);
                    set(app.ImageAxes.Children(7), 'Ydata', app.layers.B7);
                    set(app.ImageAxes.Children(8), 'Ydata', app.layers.B8);
                    set(app.ImageAxes.Children(9), 'Ydata', app.layers.B9);
                else
                    hold(app.ImageAxes);
                    plot(app.ImageAxes,app.layers.B9,'-r', 'LineWidth',1);
                    plot(app.ImageAxes,app.layers.B8,'-b', 'LineWidth',1);
                    plot(app.ImageAxes,app.layers.B7,'-g', 'LineWidth',1);
                    plot(app.ImageAxes,app.layers.B6,'-y', 'LineWidth',1);
                    plot(app.ImageAxes,app.layers.B5,'-c', 'LineWidth',1);
                    plot(app.ImageAxes,app.layers.B4,'-m', 'LineWidth',1);
                    plot(app.ImageAxes,app.layers.B3,'-y', 'LineWidth',1);
                    plot(app.ImageAxes,app.layers.B2,'-g', 'LineWidth',1);
                    plot(app.ImageAxes,app.layers.B1,'-r', 'LineWidth',1);
                    
                    drawnow;
%                     hold off;
                end
                app.layersLoaded = true;
            end
            
        end
        
        function displayROIs(app)
      
            hold(app.ImageAxes);
%             plot(app.ImageAxes,app.layers.B7,'-r', 'LineWidth',2);
            alphamask(app.ROI.reg1, [0 0 1], 0.4, app.ImageAxes);
            alphamask(app.ROI.reg2, [0 1 0], 0.4, app.ImageAxes);
            alphamask(app.ROI.reg3, [0 1 1], 0.4, app.ImageAxes);
            alphamask(app.ROI.reg4, [0.5 0 0], 0.4, app.ImageAxes);
            alphamask(app.ROI.reg5, [1 0.5 1], 0.4, app.ImageAxes);
            alphamask(app.ROI.reg6, [0 0.5 0.5], 0.4, app.ImageAxes);
            alphamask(app.ROI.reg8, [0 0 1], 0.4, app.ImageAxes);
            alphamask(app.ROI.reg9, [0 1 0], 0.4, app.ImageAxes);
            drawnow;
%             hold off;
            app.computedROIs = true;
        end
        
        function removeROIs(app)
            if app.computedROIs == true
                delete(app.ImageAxes.Children(1));
                delete(app.ImageAxes.Children(1));
                delete(app.ImageAxes.Children(1));
                delete(app.ImageAxes.Children(1));
                delete(app.ImageAxes.Children(1));
                delete(app.ImageAxes.Children(1));
                delete(app.ImageAxes.Children(1));
                delete(app.ImageAxes.Children(1));
            end
        end
        
        function initROIStatuses(app)
            app.ROIStatus = [false, false, false, false, false, false, false, false];
            
            app.firstButton.Value = false;
            app.secondButton.Value = false;
            app.thirdButton.Value = false;
            app.fourthButton.Value = false;
            app.fifthButton.Value = false;
            app.SixthButton.Value = false;
            app.SeventhButton.Value = false;
            app.EightButton.Value = false;
        end
        
        function setROIStatuses(app)
            set( findall(app.RegionsofinterestsPanel, '-property', 'Enable'), 'Enable', 'on');
            app.ROIStatus = [true, true, true, true, true, true, true, true];
            
            app.firstButton.Value = true;
            app.secondButton.Value = true;
            app.thirdButton.Value = true;
            app.fourthButton.Value = true;
            app.fifthButton.Value = true;
            app.SixthButton.Value = true;
            app.SeventhButton.Value = true;
            app.EightButton.Value = true;
        end
        
        function cleanAxes(app)
            app.ImageAxes.reset();
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            updateimage(app, app.fileName);
        end
        
        function setState(app,type)
            switch type
                case 'ROI'
                    app.computed7thLayer = false;
                    app.computedROIs = true;
                    app.computedLayers = false;
                    cleanAxes(app);
                    displayROIs(app);
                    setROIStatuses(app);
                case 'Layers'
                    app.computed7thLayer = false;
                    app.computedROIs = false;
                    app.computedLayers = true;
                 
                    initROIStatuses(app);
                    reloadLayers(app);
                    set( findall(app.RegionsofinterestsPanel, '-property', 'Enable'), 'Enable', 'off');
                       app.layersLoaded = true;
                otherwise
                    app.computed7thLayer = true;
                    app.computedROIs = false;
                    app.computedLayers = false;
                    app.layersLoaded = false;
                    initROIStatuses(app);
                    set( findall(app.RegionsofinterestsPanel, '-property', 'Enable'), 'Enable', 'off');
            end
        end
        
        function params = computeCurrentParams(app)
            global updatedParams;
            
            if isvarname('updatedParams') & ~isempty(updatedParams)
                params = updatedParams;
            else
                params = app.params;
            end
            
        end
        
        function updateCurrentParams(app, type, value)
            global updatedParams;
            
            switch type
                case 'S'
                    if isvarname('updatedParams')  && ~isempty(updatedParams)
                        updatedParams.smoothParam = value ;
                    else
                        app.params.smoothParam = value;
                    end
                case 'E'
                    if isvarname('updatedParams') && ~isempty(updatedParams)
                        updatedParams.C = value ;
                    else
                        app.params.C = value ;
                    end
                case 'SG'
                    if isvarname('updatedParams') && ~isempty(updatedParams)
                        updatedParams.gradientSmoothParam = value;
                    else
                        app.params.gradientSmoothParam = value;
                    end
                otherwise
                    if isvarname('updatedParams') && ~isempty(updatedParams)
                        updatedParams.gradientEnhaParam = value;
                    else
                        app.params.gradientEnhaParam = value;
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fileName, params)
            global layersLoaded;
            
            layersLoaded = false;
            
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            
            app.computed7thLayer = false;
            app.computedROIs = false;
            app.computedLayers = false;
            app.isDrusen = false;
            app.layersLoaded = false;
            
            app.fileName = fileName;
            % Update the image and histograms
            updateimage(app, fileName);
            
            initROIStatuses(app);
            set( findall(app.RegionsofinterestsPanel, '-property', 'Enable'), 'Enable', 'off');
            
            app.params = params;
            
            app.SmoothnessSpinner.Value = app.params.smoothParam;
            app.EnhancementSpinner.Value = app.params.C;
            app.GradientSmoothnessSpinner.Value = app.params.gradientSmoothParam;
            app.GradientEnhancementSpinner.Value = app.params.gradientEnhaParam;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {609, 609};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {533, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
        end

        % Button pushed function: PreviewsegmentationButton
        function PreviewsegmentationButtonPushed(app, event)
            [p,f,e] = extractInfoFromPath(app,app.fileName);
            
            initROIStatuses(app);
            if app.computed7thLayer == true
                cleanAxes(app);
            end
             
            if app.computedROIs == true
                cleanAxes(app);
            end
            
            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
           
            currentParams = computeCurrentParams(app);
            [app.layers, app.ROI] = segmentMultipleOCT(p,f,e,'', false, currentParams, app.isDrusen);

            set(app.UIFigure, 'pointer', 'arrow');
        
            setState(app,'Layers');
        end

        % Value changed function: SmoothnessSpinner
        function SmoothnessSpinnerValueChanged(app, event)
            value = app.SmoothnessSpinner.Value;
            
            updateCurrentParams(app, 'S',value);
%             app.params.smoothParam = value;
        end

        % Value changed function: EnhancementSpinner
        function EnhancementSpinnerValueChanged(app, event)
            value = app.EnhancementSpinner.Value;
            
            updateCurrentParams(app, 'E',value);
            app.params.C = value;
        end

        % Value changed function: GradientSmoothnessSpinner
        function GradientSmoothnessSpinnerValueChanged(app, event)
            value = app.GradientSmoothnessSpinner.Value;
            
            updateCurrentParams(app, 'SG',value);
%             app.params.gradientSmoothParam = value;
        end

        % Value changed function: GradientEnhancementSpinner
        function GradientEnhancementSpinnerValueChanged(app, event)
            value = app.GradientEnhancementSpinner.Value;
            
            updateCurrentParams(app, 'EG',value);
%             app.params.gradientEnhaParam = value;
        end

        % Callback function
        function p9thSmoothnessSpinnerValueChanged(app, event)
            value = app.p9thSmoothnessSpinner.Value;
           
            app.params.p9thSmoothParams = value;
        end

        % Button pushed function: Compute7thlayerButton
        function Compute7thlayerButtonPushed(app, event)
            [p,f,e] = extractInfoFromPath(app,app.fileName);
            
            app.l7thLayer = compute7thLayer(p,f,e,computeCurrentParams(app));
            
            cleanAxes(app);
            initROIStatuses(app);
            
            hold(app.ImageAxes);
            plot(app.ImageAxes,app.l7thLayer, '-r', 'LineWidth',2);
            setState(app,'7th')
            drawnow;
%             hold off;
        end

        % Button pushed function: ComputeROIsButton
        function ComputeROIsButtonPushed(app, event)
            [p,f,e] = extractInfoFromPath(app,app.fileName);
            
            currentParams = computeCurrentParams(app);
            
            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
            [app.layers, app.ROI] = segmentMultipleOCT(p,f,e,'',false,currentParams,app.isDrusen);
            set(app.UIFigure, 'pointer', 'arrow');
            
            setState(app,'ROI');  
        end

        % Callback function
        function DrusenButtonValueChanged(app, event)
            value = app.DrusenButton.Value;
            
            app.isDrusen = value;
        end

        % Value changed function: DrusenCheckBox
        function DrusenCheckBoxValueChanged(app, event)
            value = app.DrusenCheckBox.Value;
            
            app.isDrusen = value;
            if value == true
               set(app.ComputeROIsButton, 'Enable', 'off');
            else
               set(app.ComputeROIsButton, 'Enable', 'on');
            end
        end

        % Value changed function: firstButton
        function firstButtonValueChanged(app, event)
            value = app.firstButton.Value;
            
            app.ROIStatus(1) = value;
            
            if app.computedROIs == true
                if app.computed7thLayer == true
                    app.ImageAxes.Children(2).Visible=value;
                else
                    app.ImageAxes.Children(1).Visible=value;
                end
            end
        end

        % Value changed function: secondButton
        function secondButtonValueChanged(app, event)
            value = app.secondButton.Value;
            
            app.ROIStatus(2) = value;
            if app.computedROIs == true
               if app.computed7thLayer == true
                    app.ImageAxes.Children(3).Visible=value;
                else
                    app.ImageAxes.Children(2).Visible=value;
               end
            end
        end

        % Value changed function: thirdButton
        function thirdButtonValueChanged(app, event)
            value = app.thirdButton.Value;
            
            app.ROIStatus(3) = value;
            if app.computedROIs == true
               if app.computed7thLayer == true
                    app.ImageAxes.Children(4).Visible=value;
                else
                    app.ImageAxes.Children(3).Visible=value;
               end
            end
        end

        % Value changed function: fourthButton
        function fourthButtonValueChanged(app, event)
            value = app.fourthButton.Value;
            
            
            app.ROIStatus(4) = value;
            if app.computedROIs == true
                if app.computed7thLayer == true
                    app.ImageAxes.Children(5).Visible=value;
                else
                    app.ImageAxes.Children(4).Visible=value;
                end
            end
        end

        % Value changed function: fifthButton
        function fifthButtonValueChanged(app, event)
            value = app.fifthButton.Value;
            
            app.ROIStatus(5) = value;
            if app.computedROIs == true
                if app.computed7thLayer == true
                    app.ImageAxes.Children(6).Visible=value;
                else
                    app.ImageAxes.Children(5).Visible=value;
                end
            end
        end

        % Value changed function: SixthButton
        function SixthButtonValueChanged(app, event)
            value = app.SixthButton.Value;
            
            app.ROIStatus(6) = value;
            if app.computedROIs == true
            if app.computed7thLayer == true
                    app.ImageAxes.Children(7).Visible=value;
                else
                    app.ImageAxes.Children(6).Visible=value;
            end
            end
        end

        % Value changed function: SeventhButton
        function SeventhButtonValueChanged(app, event)
            value = app.SeventhButton.Value;
            
            app.ROIStatus(7) = value;
            if app.computedROIs == true
                if app.computed7thLayer == true
                    app.ImageAxes.Children(8).Visible=value;
                else
                    app.ImageAxes.Children(7).Visible=value;
                end
            end
        end

        % Value changed function: EightButton
        function EightButtonValueChanged(app, event)
            value = app.EightButton.Value;
            
            app.ROIStatus(8) = value;
            if app.computedROIs == true
                if app.computed7thLayer == true
                    app.ImageAxes.Children(9).Visible=value;
                else
                    app.ImageAxes.Children(8).Visible=value;
                end
            end
        end

        % Button pushed function: UpdateparamsButton
        function UpdateparamsButtonPushed(app, event)
            currentParams = computeCurrentParams(app);
            
%             updateParams(app, currentParams);
            updateParams(currentParams);
        end

        % Button pushed function: SaveconfigurationButton
        function SaveconfigurationButtonPushed(app, event)
            global configurationParams;
 
            configurationParams = computeCurrentParams(app);
            msgbox('Parameters saved!', 'Success','help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageAxes = uiaxes(app.LeftPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.ISOS7thlayerPanel = uipanel(app.RightPanel);
            app.ISOS7thlayerPanel.Title = 'IS-OS(7th layer)';
            app.SmoothnessSpinnerLabel = uilabel(app.ISOS7thlayerPanel);
            app.SmoothnessSpinnerLabel.Text = 'Smoothness';
            app.SmoothnessSpinner = uispinner(app.ISOS7thlayerPanel);
            app.SmoothnessSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothnessSpinnerValueChanged, true);
            app.EnhancementSpinnerLabel = uilabel(app.ISOS7thlayerPanel);
            app.EnhancementSpinnerLabel.Text = 'Enhancement';
            app.EnhancementSpinner = uispinner(app.ISOS7thlayerPanel);
            app.EnhancementSpinner.ValueChangedFcn = createCallbackFcn(app, @EnhancementSpinnerValueChanged, true);
            app.GradientEnhancementSpinnerLabel = uilabel(app.ISOS7thlayerPanel);
            app.GradientEnhancementSpinnerLabel.Text = 'Gradient Enhancement';
            app.GradientEnhancementSpinner = uispinner(app.ISOS7thlayerPanel);
            app.GradientEnhancementSpinner.ValueChangedFcn = createCallbackFcn(app, @GradientEnhancementSpinnerValueChanged, true);
            app.GradientSmoothnessSpinnerLabel = uilabel(app.ISOS7thlayerPanel);
            app.GradientSmoothnessSpinnerLabel.Text = 'Gradient Smoothness';
            app.GradientSmoothnessSpinner = uispinner(app.ISOS7thlayerPanel);
            app.GradientSmoothnessSpinner.ValueChangedFcn = createCallbackFcn(app, @GradientSmoothnessSpinnerValueChanged, true);
            app.RegionsofinterestsPanel = uipanel(app.RightPanel);
            app.RegionsofinterestsPanel.Title = 'Regions of interests';
            app.ChoosewhichROIstodisplayLabel = uilabel(app.RegionsofinterestsPanel);
            app.ChoosewhichROIstodisplayLabel.Text = 'Choose which ROIs to display:';
            app.firstButton = uibutton(app.RegionsofinterestsPanel, 'state');
            app.firstButton.ValueChangedFcn = createCallbackFcn(app, @firstButtonValueChanged, true);
            app.firstButton.Text = '1st';
            app.secondButton = uibutton(app.RegionsofinterestsPanel, 'state');
            app.secondButton.ValueChangedFcn = createCallbackFcn(app, @secondButtonValueChanged, true);
            app.secondButton.Text = '2nd';
            app.thirdButton = uibutton(app.RegionsofinterestsPanel, 'state');
            app.thirdButton.ValueChangedFcn = createCallbackFcn(app, @thirdButtonValueChanged, true);
            app.thirdButton.Text = '3rd';
            app.fourthButton = uibutton(app.RegionsofinterestsPanel, 'state');
            app.fourthButton.ValueChangedFcn = createCallbackFcn(app, @fourthButtonValueChanged, true);
            app.fourthButton.Text = '4th';
            app.fifthButton = uibutton(app.RegionsofinterestsPanel, 'state');
            app.fifthButton.ValueChangedFcn = createCallbackFcn(app, @fifthButtonValueChanged, true);
            app.fifthButton.Text = '5th';
            app.SixthButton = uibutton(app.RegionsofinterestsPanel, 'state');
            app.SixthButton.ValueChangedFcn = createCallbackFcn(app, @SixthButtonValueChanged, true);
            app.SixthButton.Text = '6th';
            app.SeventhButton = uibutton(app.RegionsofinterestsPanel, 'state');
            app.SeventhButton.ValueChangedFcn = createCallbackFcn(app, @SeventhButtonValueChanged, true);
            app.SeventhButton.Text = '7th';
            app.EightButton = uibutton(app.RegionsofinterestsPanel, 'state');
            app.EightButton.ValueChangedFcn = createCallbackFcn(app, @EightButtonValueChanged, true);
            app.EightButton.Text = '8th';
            app.SaveconfigurationButton = uibutton(app.RightPanel, 'push');
            app.SaveconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigurationButtonPushed, true);
            app.SaveconfigurationButton.Text = 'Save configuration';
            app.DrusenCheckBox = uicheckbox(app.RightPanel);
            app.DrusenCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrusenCheckBoxValueChanged, true);
            app.DrusenCheckBox.Text = 'Drusen';
            app.Compute7thlayerButton = uibutton(app.RightPanel, 'push');
            app.Compute7thlayerButton.ButtonPushedFcn = createCallbackFcn(app, @Compute7thlayerButtonPushed, true);
            app.Compute7thlayerButton.Text = 'Compute 7th layer';
            app.ComputeROIsButton = uibutton(app.RightPanel, 'push');
            app.ComputeROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeROIsButtonPushed, true);
            app.ComputeROIsButton.Text = 'Compute ROIs';
            app.UpdateparamsButton = uibutton(app.RightPanel, 'push');
            app.UpdateparamsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateparamsButtonPushed, true);
            app.UpdateparamsButton.Text = 'Update params';
            app.PreviewsegmentationButton = uibutton(app.RightPanel, 'push');
            app.PreviewsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewsegmentationButtonPushed, true);
            app.PreviewsegmentationButton.Text = 'Preview segmentation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigureSegmentationGUI_autoreflow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end