classdef OctSegmentationGUI_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        FluidcorrectionButtonGroup   matlab.ui.container.ButtonGroup
        DELETEfluidButton            matlab.ui.control.StateButton
        DeletefluidButton            matlab.ui.control.ToggleButton
        SegmentationBlockPanel       matlab.ui.container.Panel
        FluidButton                  matlab.ui.control.Button
        NormalButton                 matlab.ui.control.Button
        DrusenButton                 matlab.ui.control.Button
        PresegmentationBlockPanel    matlab.ui.container.Panel
        WaveletButton                matlab.ui.control.Button
        AnisotropicButton            matlab.ui.control.Button
        QIButton                     matlab.ui.control.Button
        ConfiguresegmentationButton  matlab.ui.control.Button
        AlignOCTButton               matlab.ui.control.Button
        LoadDataPanel                matlab.ui.container.Panel
        LoadlayersButton             matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        LoadButton_3                 matlab.ui.control.Button
        LoadButton_2                 matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        NoOCTScanloadedLabel         matlab.ui.control.Label
        PreviousSaveButton           matlab.ui.control.Button
        NextSaveButton               matlab.ui.control.Button
        ImageAxes                    matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        ManualsegmentationPanel      matlab.ui.container.Panel
        RevertchangesButton          matlab.ui.control.Button
        EnterManualsegmentationmodeButton  matlab.ui.control.StateButton
        SHOWLabel                    matlab.ui.control.Label
        RPEButton_3                  matlab.ui.control.StateButton
        OPLOButton                   matlab.ui.control.StateButton
        OSRPEButton                  matlab.ui.control.StateButton
        INLOPLButton                 matlab.ui.control.StateButton
        ISOSButton                   matlab.ui.control.StateButton
        IPLINLButton                 matlab.ui.control.StateButton
        ONLISButton                  matlab.ui.control.StateButton
        RNFLButton                   matlab.ui.control.StateButton
        IMLButton_2                  matlab.ui.control.StateButton
        EDITButtonGroup              matlab.ui.container.ButtonGroup
        RPEButton_4                  matlab.ui.control.RadioButton
        OSRPEButton_2                matlab.ui.control.RadioButton
        ISOSButton_2                 matlab.ui.control.RadioButton
        ONLISButton_2                matlab.ui.control.RadioButton
        OPLOButton_2                 matlab.ui.control.RadioButton
        INLOPLButton_2               matlab.ui.control.RadioButton
        IPLINLButton_2               matlab.ui.control.RadioButton
        RNFLButton_2                 matlab.ui.control.RadioButton
        IMLButton                    matlab.ui.control.RadioButton
        PostsegmentationBlockPanel   matlab.ui.container.Panel
        ExportthicknessButton        matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        Layers = []; % rendered layers
        RPECH_layer; % Boolean variable to render the layer or not
        OSRPE_layer;
        ISOS_layer;
        ONLIS_layer;
        OPLO_layer;
        INLOPL_layer;
        IPLINL_layer;
        RNFL_layer;
        ILM_layer;

        pathToOCTScan;
        pathToLayers;
        OCTScanName;
        OCTScanExt;
        FileNames = [];
        Files = [];
        scanIndex;
        SegmentedImages = [];
        FluidMasks = [];
        alreadySegmented;
        inEditMode;
        params;
        deletingFluid;
        fluid;
        labeledFluid;
        isFluid;
        addingFluid; % Description
        FluidMode;
        LayersMode;
        singleOCTMode;
    end


    methods (Access = private)


        function updateimage(app,imagefile)

            global layersLoaded;

            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            end

            global imgSize;

            imgSize = size(im);

            layersLoaded = false;
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image

                    displayedImg = imagesc(app.ImageAxes,im);
                    %                     hold(app.ImageAxes);
                    %                     title(app.ImageAxes,'sergiu');

                case 3
                    % Display the truecolor image
                    displayedImg = imagesc(app.ImageAxes,im);
                    %                     hold on;


                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end


        end

        function loadLayers(app, dname, fname, fileIndex)

            if (ischar(dname))
                if isfolder(dname)
                    cd(dname);

                    originalSegmentation = load([fname '.mat']);

                    app.SegmentedImages = [app.SegmentedImages, originalSegmentation];
                    app.params = originalSegmentation.params;

                    disp(app.SegmentedImages);
                    cd('..\');

                    app.pathToLayers = dname;
                end
            end
        end


        function reloadLayers(app, scanIndex)
            global layersLoaded;
            global layerToEdit;
            global layerNameToEdit;

            if length(app.SegmentedImages) >= scanIndex
                app.Layers = app.SegmentedImages(scanIndex).Layers;

                if layersLoaded == true
                    set(app.ImageAxes.Children(1), 'Ydata', app.Layers(1,:,:));
                    set(app.ImageAxes.Children(2), 'Ydata', app.Layers(2,:,:));
                    set(app.ImageAxes.Children(3), 'Ydata', app.Layers(3,:,:));
                    set(app.ImageAxes.Children(4), 'Ydata', app.Layers(4,:,:));
                    set(app.ImageAxes.Children(5), 'Ydata', app.Layers(5,:,:));
                    set(app.ImageAxes.Children(6), 'Ydata', app.Layers(6,:,:));
                    set(app.ImageAxes.Children(7), 'Ydata', app.Layers(7,:,:));
                    set(app.ImageAxes.Children(8), 'Ydata', app.Layers(8,:,:));
                    set(app.ImageAxes.Children(9), 'Ydata', app.Layers(9,:,:));

                    app.EDITButtonGroup.SelectedObject = app.EDITButtonGroup.Buttons(1);

                else
                    hold(app.ImageAxes);
                    plot(app.ImageAxes,app.Layers(9,:,:),'-r', 'LineWidth',1);
                    plot(app.ImageAxes,app.Layers(8,:,:),'-b', 'LineWidth',1);
                    plot(app.ImageAxes,app.Layers(7,:,:),'-g', 'LineWidth',1);
                    plot(app.ImageAxes,app.Layers(6,:,:),'-y', 'LineWidth',1);
                    plot(app.ImageAxes,app.Layers(5,:,:),'-c', 'LineWidth',1);
                    plot(app.ImageAxes,app.Layers(4,:,:),'-m', 'LineWidth',1);
                    plot(app.ImageAxes,app.Layers(3,:,:),'-y', 'LineWidth',1);
                    plot(app.ImageAxes,app.Layers(2,:,:),'-g', 'LineWidth',1);
                    plot(app.ImageAxes,app.Layers(1,:,:),'-r', 'LineWidth',1);

                    drawnow;
                    hold(app.ImageAxes);
                end


                layersLoaded = true;

                %                 TODO Change with global handling of layerse editing
                layerToEdit = app.Layers(1,:,:);
                layerNameToEdit = 'IML';
                app.IMLButton.Value = true;
            end
        end

        function exportThickness(app)

            RPECH_OSRPE = app.Layers(9,:,:) - app.Layers(8,:,:);
            OSRPE_ISOS = app.Layers(8,:,:) - app.Layers(7,:,:);
            ISOS_ONLIS =app.Layers(7,:,:) - app.Layers(6,:,:);
            ONLIS_OPLO = app.Layers(6,:,:) - app.Layers(5,:,:);
            OPLO_INLOPL =app.Layers(5,:,:) - app.Layers(4,:,:);
            INLOPL_IPLINL = app.Layers(4,:,:) - app.Layers(3,:,:);
            IPLINL_RNFL = app.Layers(3,:,:) - app.Layers(2,:,:);
            RNFL_IML = app.Layers(2,:,:) - app.Layers(1,:,:);

            layers = [
                RPECH_OSRPE;
                OSRPE_ISOS;
                ISOS_ONLIS;
                ONLIS_OPLO;
                OPLO_INLOPL;
                INLOPL_IPLINL;
                IPLINL_RNFL;
                RNFL_IML;
                ];

            writematrix(layers, [app.pathToOCTScan  app.OCTScanName '_thickness.csv']);
        end

        function loadFromXml(app, path, name, ext)
            if strcmp(ext,'.xml') == 1
                docNode = xmlread(strcat(path, [name ext]));
                data = docNode.getElementsByTagName('ExamURL');
                im_slo = char(data.item(0).getFirstChild.getData);

                loc = strfind(im_slo,  '/');
                im_slo(1:loc(end)) = [];


                try
                    imread([path im_slo]);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end


                no_of_files = data.getLength - 1;


                for k = 1:no_of_files
                    %                     docNode = xmlread(strcat(path, [name ext]));
                    data = docNode.getElementsByTagName('ExamURL');
                    im_oct = char(data.item(k).getFirstChild.getData);
                    loc = strfind(im_oct,  '/');
                    im_oct(1:loc(end)) = [];
                    %                     imgName = [path im_oct];
                    [~, fName1, fExt1] = fileparts(im_oct);

                    app.FileNames = [app.FileNames; [path, fName1, fExt1]];

                    if app.alreadySegmented == true
                        [p,f,~] = extractInfoFromPath(app, k);
                        loadLayers(app, p, f, k);
                    end

                end

                loadImages(app);
            end
        end

        function loadImages(app)

            app.scanIndex = 1;
            updateimage(app, app.FileNames(1,:,:));

            updateNumLabel(app);
            if app.alreadySegmented == true
                reloadLayers(app, app.scanIndex);
            end
        end

        function updateNumLabel(app)
            [m,~] = size(app.FileNames);
            app.NoOCTScanloadedLabel.Text = [num2str(app.scanIndex) '/' num2str(m)];
            drawnow;
        end

        function resetVariables(app)
            app.Layers = [];
            app.FileNames = [];
            app.SegmentedImages = [];
            app.FluidMasks = [];
            app.inEditMode = false;
        end

        function [path,name,ext] = extractInfoFromPath(app, index)
            layersFolder = split(app.FileNames(index,:,:),'\');
            [m,~] = size(layersFolder);
            fileName = char(layersFolder(m));
            layersFolder = char(join(layersFolder(1:m-1)));
            layersFolder = strrep(layersFolder, ' ','\');
            layersFolder = [layersFolder '\'];
            fileName = split(fileName, '.');
            ext = ['.' char(fileName(2))];
            fileName = char(fileName(1));

            path = layersFolder;
            name = fileName;
        end

        function goToNextPicture(app, m)

            if app.scanIndex < m
                app.scanIndex = app.scanIndex + 1;
                image = app.FileNames(app.scanIndex,:, :);

                updateimage(app,image);

                updateNumLabel(app);
            elseif app.scanIndex == m
                app.scanIndex = 1;
                image = app.FileNames(app.scanIndex,:, :);
                updateimage(app,image);

                updateNumLabel(app);
            end

            if app.FluidMode == true
                drawFluid(app, app.scanIndex);

            elseif app.alreadySegmented == true || ~isempty(app.Layers)
                reloadLayers(app, app.scanIndex);
                enableManualSegmentationPanel(app);
            end
        end

        function goToPreviousPicture(app, m)
            %             app.SegmentedImages(app.scanIndex).Layers = app.Layers;

            if app.scanIndex > 1
                app.scanIndex = app.scanIndex - 1;
                image = app.FileNames(app.scanIndex,:, :);
                updateimage(app,image);

                updateNumLabel(app);
            elseif app.scanIndex == 1
                app.scanIndex = m;
                image = app.FileNames(app.scanIndex,:, :);
                updateimage(app,image);

                updateNumLabel(app);
            end

            if app.FluidMode == true
                drawFluid(app,app.scanIndex);
            elseif app.alreadySegmented == true || ~isempty(app.Layers)
                reloadLayers(app, app.scanIndex);
                enableManualSegmentationPanel(app);
            end
        end

        function drawFluid(app, index)
            resetImageAxes(app);
            image = app.FileNames(index,:, :);
            updateimage(app,image);

            if length(app.FluidMasks) >= index
                hold(app.ImageAxes);
                alphamask(app.FluidMasks(index).binary, [0 0 1], 0.4, app.ImageAxes);
                %                 hold off;

                app.fluid = app.FluidMasks(index);
            end
        end

        function enableManualSegmentationPanel(app)
            app.RPECH_layer = true;
            app.OSRPE_layer = true;
            app.ISOS_layer = true;
            app.ONLIS_layer = true;
            app.OPLO_layer = true;
            app.INLOPL_layer = true;
            app.IPLINL_layer = true;
            app.RNFL_layer = true;
            app.ILM_layer = true;

            app.IMLButton_2.Value = true;
            app.RNFLButton.Value = true;
            app.ONLISButton.Value = true;
            app.RPEButton_3.Value = true;
            app.OSRPEButton.Value = true;
            app.ISOSButton.Value = true;
            app.OPLOButton.Value = true;
            app.INLOPLButton.Value = true;
            app.IPLINLButton.Value = true;

            set(app.IMLButton_2,'Enable','on');
            set(app.RPEButton_3,'Enable','on');
            set(app.OPLOButton,'Enable','on');
            set(app.OSRPEButton,'Enable','on');
            set(app.IPLINLButton,'Enable','on');
            set(app.INLOPLButton,'Enable','on');
            set(app.ISOSButton,'Enable','on');
            set(app.ONLISButton,'Enable','on');
            set(app.RNFLButton,'Enable','on');

            set(app.EnterManualsegmentationmodeButton, 'Enable', 'on');
            set(app.RevertchangesButton, 'Enable', 'on');
            set( findall(app.EDITButtonGroup, '-property', 'Enable'), 'Enable', 'on');
            set(findall(app.ImageAxes.Children),'visible','on');
        end

        function disableManualSegmentationPanel(app)
            app.RPECH_layer = false;
            app.OSRPE_layer = false;
            app.ISOS_layer = false;
            app.ONLIS_layer = false;
            app.OPLO_layer = false;
            app.INLOPL_layer = false;
            app.IPLINL_layer = false;
            app.RNFL_layer = false;
            app.ILM_layer = false;

            set(app.RPEButton_3,'Enable','off');
            set(app.IMLButton_2,'Enable','off');
            set(app.OPLOButton,'Enable','off');
            set(app.OSRPEButton,'Enable','off');
            set(app.IPLINLButton,'Enable','off');
            set(app.INLOPLButton,'Enable','off');
            set(app.ISOSButton,'Enable','off');
            set(app.ONLISButton,'Enable','off');
            set(app.RNFLButton,'Enable','off');

            set(app.EnterManualsegmentationmodeButton, 'Enable', 'off');
            set(app.RevertchangesButton, 'Enable', 'off');
            set( findall(app.EDITButtonGroup, '-property', 'Enable'), 'Enable', 'off');
        end

        function resetImageAxes(app)
            cla(app.ImageAxes,'reset');
            title(app.ImageAxes, 'Images');
            app.ImageAxes.AmbientLightColor = 'none';
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxes.Color = 'none';
            app.ImageAxes.HandleVisibility = 'off';
            app.ImageAxes.Position = [1 87 480 565];

            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            disableDefaultInteractivity(app.ImageAxes);
        end

        function saveSegmentation(app)
            global imgSize;

            Y = 1:imgSize(2);
            [~,n] = size(app.SegmentedImages);

            for k = 1:n
                segmentedImage = app.SegmentedImages(k);
                segmentedImage.params = computeCurrParams(app);

                [p,f,~] = extractInfoFromPath(app, k);

                save([p f '.mat'],'-struct', 'segmentedImage');

            end
            %             img = app.ImageAxes;
            %
            %             filename = [layersFolder '_segmentation.tif'];
            %             disp(filename);
            %             exportgraphics(img, filename );
        end

        function saveFluidSegmentation(app)
            [~,n] = size(app.FluidMasks);
            segmentedVisit = [];

            for k = 1:n
                entry = struct();
                entry.name = app.FileNames(k,:,:);
                entry.fluid = app.FluidMasks(k);
                entry.params = computeCurrParams(app);

                segmentedVisit = [segmentedVisit entry];
            end

            [p,~,~] = extractInfoFromPath(app, k);

            save([p 'fluid_segmentation.mat'], 'segmentedVisit');
        end

        function params = computeCurrParams(app)
            global configurationParams;

            if isvarname('configurationParams') && ~isempty(configurationParams)
                params = configurationParams;
            else
                params = app.params;
            end
        end

        function setState(app, type)
            switch type
                case 'DRUSEN'
                    app.deletingFluid = false;
                    app.FluidMode = false;
                    app.LayersMode = true;
                    set( findall(app.FluidcorrectionButtonGroup, '-property', 'Enable'), 'Enable', 'off');
                    enableManualSegmentationPanel(app);
                    set(app.ExportthicknessButton, 'Enable', 'on');
                case 'NORMAL'
                    app.deletingFluid = false;
                    app.FluidMode = false;
                    app.LayersMode = true;
                    set( findall(app.FluidcorrectionButtonGroup, '-property', 'Enable'), 'Enable', 'off');
                    enableManualSegmentationPanel(app);
                    set(app.ExportthicknessButton, 'Enable', 'on');
                otherwise
                    app.FluidMode = true;
                    app.LayersMode = false;
                    disableManualSegmentationPanel(app);
                    set( findall(app.FluidcorrectionButtonGroup, '-property', 'Enable'), 'Enable', 'on');
                    set(app.ExportthicknessButton, 'Enable', 'off');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global layersLoaded;
            %             global configurationParams;
            layersLoaded = false;

            resetImageAxes(app);

            app.deletingFluid = false;
            app.isFluid = false;
            app.addingFluid = false;
            set( findall(app.FluidcorrectionButtonGroup, '-property', 'Enable'), 'Enable', 'off');

            set( findall(app.EDITButtonGroup, '-property', 'Enable'), 'Enable', 'off');
            app.FluidMode = false;
            app.LayersMode = false;
            app.singleOCTMode = false;

            disableManualSegmentationPanel(app);


            app.params = struct();
            app.params.C = 0.001;
            app.params.smoothParam = 0.02;
            app.params.gradientSmoothParam = 0.02;
            app.params.gradientEnhaParam = 1;
            app.params.p9thAboveBandWidth = 10;
            app.params.p9thSmoothParam = 0.0002;
            app.params.p8thAboveBandWidth = 0;
            app.params.p8thBelowBandWidth = 8;
            app.params.p6thAboveBandWidth = 15;
            app.params.p6thBelowBandWidth = 2;
            app.params.p1stBelowBandWidth = 25;
            app.params.p4thAboveBandWidth2 = 20;
            app.params.p4thBelowBandWidth2 = 1;
            app.params.p3thAboveBandWidth = 4;
            app.params.p3thBelowBandWidth = 14;
            app.params.p2thAboveBandWidth = 4;
            app.params.p2thBelowBandWidth = 8;

            %             TODO
            %             configurationParams = app.params;

        end

        % Callback function
        function DropDownValueChanged(app, event)

            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {631, 631, 631};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {631, 631};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {297, '1x', 409};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            % Display uigetfile dialog
            filterspec = {'*.jpg;*.jpeg;*.tif;*.png;*.gif','All Image Files'};
            [file, path] = uigetfile(filterspec);
            [p, f, e] = fileparts([path file]);
            p = [p '\'];


            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                app.pathToOCTScan = p;
                app.OCTScanName = f;
                app.OCTScanExt = e;
                fname = [p f e];
                updateimage(app, fname);
                app.singleOCTMode = true;
                if( isfolder([p f]))
                    loadLayers(app, p,f, 0);
                end
            end
        end

        % Button pushed function: LoadlayersButton
        function LoadlayersButtonPushed(app, event)
            %             dname = uigetdir('/media/sergiu/Shared/Licenta/Tools/OCTSegExamples/Visit_1\');
            dname = uigetdir();

            loadLayers(app, dname,0);
        end

        % Selection changed function: EDITButtonGroup
        function EDITButtonGroupSelectionChanged(app, event)
            selectedButton = app.EDITButtonGroup.SelectedObject;

            global layerToEdit;
            global layerNameToEdit;
            if app.LayersMode == true
                if strcmp(selectedButton.Text,'IML') == 1
                    layerToEdit = app.Layers(1,:,:);
                    layerNameToEdit = 'IML';
                elseif strcmp(selectedButton.Text, 'RNFL') == 1
                    layerToEdit = app.Layers(2,:,:);
                    layerNameToEdit = 'RNFL';
                elseif strcmp(selectedButton.Text, 'IPL-INL') == 1
                    layerToEdit = app.Layers(3,:,:);
                    layerNameToEdit = 'IPLINL';
                elseif strcmp(selectedButton.Text, 'INL-OPL') == 1
                    layerToEdit = app.Layers(4,:,:);
                    layerNameToEdit = 'INLOPL';
                elseif strcmp(selectedButton.Text, 'OPLO') == 1
                    layerToEdit = app.Layers(5,:,:);
                    layerNameToEdit = 'OPLO';
                elseif strcmp(selectedButton.Text, 'ONL-IS') == 1
                    layerToEdit = app.Layers(6,:,:);
                    layerNameToEdit = 'ONLIS';
                elseif strcmp(selectedButton.Text, 'IS-OS') == 1
                    layerToEdit = app.Layers(7,:,:);
                    layerNameToEdit = 'ISOS';
                elseif strcmp(selectedButton.Text, 'OS-RPE') == 1
                    layerToEdit = app.Layers(8,:,:);
                    layerNameToEdit = 'OSRPE';
                elseif strcmp(selectedButton.Text, 'RPE') == 1
                    layerToEdit = app.Layers(9,:,:);
                    layerNameToEdit = 'RPECH';
                end
            end

        end

        % Value changed function: IMLButton_2
        function IMLButton_2ValueChanged(app, event)
            if app.ILM_layer == false
                set(app.ImageAxes.Children(1),'visible','on');
                app.ILM_layer = true;
            else
                set(app.ImageAxes.Children(1),'visible','off');
                app.ILM_layer = false;
            end
        end

        % Value changed function: RNFLButton
        function RNFLButtonValueChanged(app, event)
            if app.RNFL_layer == false
                set(app.ImageAxes.Children(2),'visible','on');
                app.RNFL_layer = true;
            else
                set(app.ImageAxes.Children(2),'visible','off');
                app.RNFL_layer = false;
            end
        end

        % Value changed function: IPLINLButton
        function IPLINLButtonValueChanged(app, event)
            if app.IPLINL_layer == false
                set(app.ImageAxes.Children(3),'visible','on');
                app.IPLINL_layer = true;
            else
                set(app.ImageAxes.Children(3),'visible','off');
                app.IPLINL_layer = false;
            end
        end

        % Value changed function: INLOPLButton
        function INLOPLButtonValueChanged(app, event)
            if app.INLOPL_layer == false
                set(app.ImageAxes.Children(4),'visible','on');
                app.INLOPL_layer = true;
            else
                set(app.ImageAxes.Children(4),'visible','off');
                app.INLOPL_layer = false;
            end
        end

        % Value changed function: OPLOButton
        function OPLOButtonValueChanged(app, event)
            if app.OPLO_layer == false
                set(app.ImageAxes.Children(5),'visible','on');
                app.OPLO_layer = true;
            else
                set(app.ImageAxes.Children(5),'visible','off');
                app.OPLO_layer = false;
            end
        end

        % Value changed function: ONLISButton
        function ONLISButtonValueChanged(app, event)
            if app.ONLIS_layer == false
                set(app.ImageAxes.Children(6),'visible','on');
                app.ONLIS_layer = true;
            else
                set(app.ImageAxes.Children(6),'visible','off');
                app.ONLIS_layer = false;
            end
        end

        % Value changed function: ISOSButton
        function ISOSButtonValueChanged(app, event)
            if app.ISOS_layer == false
                set(app.ImageAxes.Children(7),'visible','on');
                app.ISOS_layer = true;
            else
                set(app.ImageAxes.Children(7),'visible','off');
                app.ISOS_layer = false;
            end
        end

        % Value changed function: OSRPEButton
        function OSRPEButtonValueChanged(app, event)
            if app.OSRPE_layer == false
                set(app.ImageAxes.Children(8),'visible','on');
                app.OSRPE_layer = true;
            else
                set(app.ImageAxes.Children(8),'visible','off');
                app.OSRPE_layer = false;
            end
        end

        % Value changed function: RPEButton_3
        function RPEButton_3ValueChanged(app, event)
            if app.RPECH_layer == false
                set(app.ImageAxes.Children(9),'visible','on');
                app.RPECH_layer = true;
            else
                set(app.ImageAxes.Children(9),'visible','off');
                app.RPECH_layer = false;
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            global mousePressed;
            global pointBF;
            global imgSize;
            global layersLoaded;

            currentPoint = app.ImageAxes.CurrentPoint;

            if layersLoaded == true && app.inEditMode==true && currentPoint(1) > 0 && currentPoint(3) > 0 && (currentPoint(4) < imgSize(1)) && currentPoint(2) < imgSize(2)
                pointBF = [round(currentPoint(1)), round(currentPoint(3))];

                mousePressed = true;
            end

            if app.deletingFluid==true && currentPoint(1) > 0 && currentPoint(3) > 0 && (currentPoint(4) < imgSize(1)) && currentPoint(2) < imgSize(2)
                pointBF = [round(currentPoint(1)), round(currentPoint(3))];

                val = app.fluid.labeled(round(currentPoint(3)),round(currentPoint(1)));

                app.fluid.labeled(app.fluid.labeled==val) = 0;
                app.fluid.binary = app.fluid.labeled > 0;
                app.fluid.volume = sum(app.fluid.binary(:));

                delete(app.ImageAxes.Children(1));
                alphamask(app.fluid.binary, [0 0 1], 0.4, app.ImageAxes);
                drawnow;
            end

            if app.addingFluid == true && currentPoint(1) > 0 && currentPoint(3) > 0 && (currentPoint(4) < imgSize(1)) && currentPoint(2) < imgSize(2)
                pointBF = [round(currentPoint(1)), round(currentPoint(3))];

                image = get(app.ImageAxes.Children(2),'CData');
                image = image(:,:,1);

                [mask,L] = extractFluidOnClick(image,round(currentPoint(1)),round(currentPoint(3)), app.params);

                app.fluid.binary = or(app.fluid, mask);
                %                 app.fluid.labeled TODO
                delete(app.ImageAxes.Children(1));
                alphamask(app.fluid.binary, [0 0 1], 0.4, app.ImageAxes);
                %                 L = labelmatrix(CC);

            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            global mousePressed;
            global imgSize;
            global layerToEdit;
            global layerNameToEdit;

            global pointBF;

            switched = 0;
            currentPoint = app.ImageAxes.CurrentPoint;


            if mousePressed == true & currentPoint(1) > 0 & currentPoint(3) > 0 & (currentPoint(4) < imgSize(1)) & currentPoint(2) < imgSize(2)


                actPoint = [round(currentPoint(1)), round(currentPoint(3))];


                if actPoint(1) < pointBF(1)
                    temp = pointBF;
                    pointBF = actPoint;
                    actPoint = temp;
                    switched = 1;
                end


                diff = actPoint(2) - pointBF(2);
                diffY = actPoint(1) - pointBF(1);

                if pointBF(1) == 1
                    updateColumns = pointBF(1):1:actPoint(1) + 1;
                elseif actPoint(1) == imgSize(2)
                    updateColumns = pointBF(1)-1:1:actPoint(1);
                else
                    updateColumns = pointBF(1)-1:1:actPoint(1) + 1;
                end

                if diffY == 0
                    for i = updateColumns
                        layerToEdit(i) = pointBF(2);
                    end
                else
                    for i = updateColumns
                        layerToEdit(i) = pointBF(2) + diff * ((i - pointBF(1)) / abs(diffY));
                    end
                end

                if switched == 0
                    pointBF = actPoint;
                end

                %                 drawnow;
                %                 pause(0.005);
                switch layerNameToEdit
                    case 'IML'
                        set(app.ImageAxes.Children(1), 'Ydata', layerToEdit);
                        app.Layers(1,:,:) = layerToEdit;
                    case 'RNFL'
                        set(app.ImageAxes.Children(2), 'Ydata', layerToEdit);
                        app.Layers(2,:,:) = layerToEdit;
                    case 'IPLINL'
                        set(app.ImageAxes.Children(3), 'Ydata', layerToEdit);
                        app.Layers(3,:,:) = layerToEdit;
                    case 'INLOPL'
                        set(app.ImageAxes.Children(4), 'Ydata', layerToEdit);
                        app.Layers(4,:,:) = layerToEdit;
                    case 'OPLO'
                        set(app.ImageAxes.Children(5), 'Ydata', layerToEdit);
                        app.Layers(5,:,:) = layerToEdit;
                    case 'ONLIS'
                        set(app.ImageAxes.Children(6), 'Ydata', layerToEdit);
                        app.Layers(6,:,:) = layerToEdit;
                    case 'ISOS'
                        set(app.ImageAxes.Children(7), 'Ydata', layerToEdit);
                        app.Layers(7,:,:) = layerToEdit;
                    case 'OSRPE'
                        set(app.ImageAxes.Children(8), 'Ydata', layerToEdit);
                        app.Layers(8,:,:) = layerToEdit;
                    otherwise
                        set(app.ImageAxes.Children(9), 'Ydata', layerToEdit);
                        app.Layers(9,:,:) = layerToEdit;
                end
                drawnow;
                %                 pause(0.005);

            end


        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            global mousePressed;
            mousePressed = false;
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            %             verticalScrollAmount = event.VerticalScrollAmount;
            %             verticalScrollCount = event.VerticalScrollCount;

        end

        % Button pushed function: RevertchangesButton
        function RevertchangesButtonPushed(app, event)
            reloadLayers(app, app.scanIndex);
            enableManualSegmentationPanel(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.FluidMode == false
                saveSegmentation(app);
            else
                fig = app.UIFigure;
                %                 msg = strcat('Segmentation will be saved as: \bffluid_segmentation.mat\rm');
                msg = ['Segmentation will be saved as: fluid_segmentation.mat' newline 'Saving these changes will overwrite previous changes.'];
                title = 'Save fluid segmentation';
                selection = uiconfirm(fig,msg,title,...
                    'Options',{'Overwrite','Save as new','Cancel'},...
                    'DefaultOption',1,'CancelOption',3);

                saveFluidSegmentation(app);
            end

            msgbox('Operation successful!', 'Success','help');
        end

        % Button pushed function: ExportthicknessButton
        function ExportthicknessButtonPushed(app, event)
            exportThickness(app);
        end

        % Value changed function: EnterManualsegmentationmodeButton
        function EnterManualsegmentationmodeButtonValueChanged(app, event)
            value = app.EnterManualsegmentationmodeButton.Value;


            app.inEditMode = value;
        end

        % Button pushed function: LoadButton_2
        function LoadNewXMLPushed(app, event)
            resetVariables(app);

            % Display uigetfile dialog
            filterspec = {'*.xml;'};
            [file, path] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))

                [p, f, e] = fileparts([path file]);
                p = [p '\'];
                app.alreadySegmented = false;
                app.singleOCTMode = false;

                app.pathToOCTScan = p;
                app.OCTScanName = f;
                app.OCTScanExt = e;

                loadFromXml(app, p, f, e);

            end
        end

        % Button pushed function: LoadButton_3
        function LoadSegmentedXMLPushed(app, event)
            resetVariables(app);

            % Display uigetfile dialog
            filterspec = {'*.xml;'};
            [file, path] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                [p, f, e] = fileparts([path file]);
                p = [p '\'];

                app.alreadySegmented = true;
                app.singleOCTMode = false;

                app.pathToOCTScan = p;
                app.OCTScanName = f;
                app.OCTScanExt = e;

                loadFromXml(app, p, f, e);
            end
        end

        % Button pushed function: NextSaveButton
        function LoadNextImagePushed(app, event)
            [m,~] = size(app.FileNames);

            if length(app.SegmentedImages) >= app.scanIndex && app.FluidMode==false
                app.SegmentedImages(app.scanIndex).Layers = app.Layers;
            end
            if app.FluidMode==true && length(app.FluidMasks) >= app.scanIndex
                app.FluidMasks(app.scanIndex) = app.fluid;
            end

            goToNextPicture(app,m);

        end

        % Button pushed function: PreviousSaveButton
        function LoadPreviousImagePushed(app, event)
            [m,~] = size(app.FileNames);

            if length(app.SegmentedImages) >= app.scanIndex && app.FluidMode==false
                app.SegmentedImages(app.scanIndex).Layers = app.Layers;
            end
            if app.FluidMode==true && length(app.FluidMasks) >= app.scanIndex
                app.FluidMasks(app.scanIndex) = app.fluid;
            end
            goToPreviousPicture(app, m);
        end

        % Button pushed function: AlignOCTButton
        function AlignOCTButtonPushed(app, event)
            close all;

            answer = questdlg('Aceasta operatie va dura aprox. un minut. Sunteti sigur ca doriti sa continuati?', ...
                'Atentionare', 'Continua','Anuleaza', 'Anuleaza');

            if strcmp(answer,'Continua')==1
                [len,~] = size(app.FileNames);
                %             len = 6;
                [p, ~,~] = extractInfoFromPath(app,1);
                previousFolder = pwd;
                mkdir([p 'aligned']);
                cd([p 'aligned']);

                auxFileNames = [];
                app.SegmentedImages = [];

                set(app.UIFigure, 'pointer', 'watch')
                drawnow;
                app.scanIndex = 1;

                for k = 1:len
                    [p, f,e] = extractInfoFromPath(app,k);
                    fileName = [p f e];
                    im = curvCorrectionAMPAO(fileName);

                    im = double(im);
                    im = mat2gray(im);
                    imwrite(im, [f e]);

                    path = [p 'aligned\'];
                    auxFileNames = [auxFileNames; [path, f, e]];

                    updateimage(app,[path, f, e]);

                    updateNumLabel(app);
                    if app.scanIndex ~= len
                        app.scanIndex = app.scanIndex + 1;
                    end

                end

                cd(previousFolder);
                set(app.UIFigure, 'pointer', 'arrow')

                app.FileNames = auxFileNames;

                set( findall(app.FluidcorrectionButtonGroup, '-property', 'Enable'), 'Enable', 'off')

                uiwait(msgbox('Operation Completed','Success','help'));
            end
        end

        % Button pushed function: ConfiguresegmentationButton
        function ConfiguresegmentationButtonPushed(app, event)
            if app.singleOCTMode == false
                fileName = app.FileNames(app.scanIndex,:,:);

            else
                fileName = strcat(app.pathToOCTScan, app.OCTScanName, app.OCTScanExt);

            end

            currentParams = computeCurrParams(app);
            ConfigureSegmentationGUI_autoreflow(fileName, currentParams);
        end

        % Button pushed function: DrusenButton
        function DrusenButtonPushed(app, event)
            global loadedFirst;

            answer = questdlg('Aceasta operatie va dura cateva minute. Sunteti sigur ca doriti sa continuati?', ...
                'Atentionare', 'Continua','Anuleaza', 'Anuleaza');

            if strcmp(answer,'Continua')==1
                if app.singleOCTMode == false
                    app.SegmentedImages = [];

                    currentParams = computeCurrParams(app);

                    resetImageAxes(app);
                    [layersFolder, fileName, ext] = extractInfoFromPath(app,1);
                    updateimage(app,[layersFolder fileName ext]);

                    set(app.UIFigure, 'pointer', 'watch')
                    drawnow;

                    [len,~] = size(app.FileNames);
                    %             len = 2;
                    app.scanIndex = 1;
                    for k = 1:len
                        [layersFolder, fileName, ext] = extractInfoFromPath(app,k);

                        subFolderName = '';

                        segmentMultipleOCT(layersFolder, fileName, ext, subFolderName, true, currentParams, false);

                        updateimage(app,[layersFolder fileName ext]);
                        loadLayers(app,layersFolder,fileName,k);
                        reloadLayers(app, app.scanIndex);

                        updateNumLabel(app);
                        if app.scanIndex ~= len
                            app.scanIndex = app.scanIndex + 1;
                        end

                        needResetAxes = false;
                        loadedFirst = true;
                    end
                else
                    subFolderName = '';

                    fileName = app.OCTScanName;
                    layersFolder = app.pathToOCTScan;
                    ext = app.OCTScanExt;
                    currentParams = computeCurrParams(app);

                    segmentMultipleOCT(layersFolder, fileName, ext, subFolderName, true, currentParams, false);
                     updateimage(app,[layersFolder fileName ext]);
                end

                set(app.UIFigure, 'pointer', 'arrow')
                setState(app,'DRUSEN');
                %             app.UIFigure.Pointer

                msgbox('Done!', 'Success','help');
            end
        end

        % Button pushed function: NormalButton
        function NormalButtonPushed(app, event)
            global loadedFirst;

            answer = questdlg('Aceasta operatie va dura cateva minute. Sunteti sigur ca doriti sa continuati?', ...
                'Atentionare', 'Continua','Anuleaza', 'Anuleaza');

            if strcmp(answer,'Continua')==1
                currentParams = computeCurrParams(app);
                app.SegmentedImages = [];

                needResetAxes = false;

                if app.FluidMode == true
                    needResetAxes = true;
                end

                set(app.UIFigure, 'pointer', 'watch')
                drawnow;

                [len,~] = size(app.FileNames);
                app.scanIndex = 1;
                %             len = 4;
                for k = 1:len
                    [layersFolder, fileName, ext] = extractInfoFromPath(app,k);

                    subFolderName = '';

                    segmentMultipleOCT(layersFolder, fileName, ext, subFolderName, true, currentParams, true);

                    if needResetAxes == true
                        resetImageAxes(app);
                    end

                    updateimage(app,[layersFolder fileName ext]);
                    loadLayers(app,layersFolder,fileName,k);
                    reloadLayers(app, app.scanIndex);
                    disp(app.ImageAxes.Children);

                    loadedFirst = true;
                    needResetAxes = false;

                    updateNumLabel(app);
                    if app.scanIndex ~= len
                        app.scanIndex = app.scanIndex + 1;
                    end
                end

                set(app.UIFigure, 'pointer', 'arrow')
                setState(app,'NORMAL');
                msgbox('Done!', 'Success','help');
            end;
        end

        % Button pushed function: FluidButton
        function FluidButtonPushed(app, event)
            global configurationParams;

            %             if is(configurationParams)
            %             app.params = configurationParams;
            answer = questdlg('Aceasta operatie va dura aprox. un minut. Sunteti sigur ca doriti sa continuati?', ...
                'Atentionare', 'Continua','Anuleaza', 'Anuleaza');

            if strcmp(answer,'Continua')==1
                currentParams = computeCurrParams(app);

                app.FluidMasks = [];

                disableManualSegmentationPanel(app);

                set(app.UIFigure, 'pointer', 'watch')
                drawnow;

                [len,~] = size(app.FileNames);
                app.scanIndex = 1;
                %             len = 1;
                for k = 1:len
                    [layersFolder, fileName, ext] = extractInfoFromPath(app,k);

                    [~, app.labeledFluid] = fluidSegmentation(layersFolder,fileName,ext,currentParams);
                    fluidd = app.labeledFluid > 0;


                    app.fluid = struct();
                    app.fluid.binary = fluidd;
                    app.fluid.labeled = app.labeledFluid;
                    app.fluid.volume = sum(fluidd(:));

                    app.FluidMasks = [app.FluidMasks, app.fluid];

                    disp([layersFolder fileName ext]);

                    resetImageAxes(app);

                    updateimage(app,[layersFolder fileName ext]);
                    drawnow;


                    hold(app.ImageAxes);
                    alphamask(fluidd, [0 0 1], 0.4, app.ImageAxes);
                    %                 hold off;

                    disp(app.scanIndex);

                    updateNumLabel(app);

                    app.scanIndex = app.scanIndex + 1;

                end

                set(app.UIFigure, 'pointer', 'arrow')

                app.scanIndex = app.scanIndex - 1;

                setState(app,'FLUID');
                msgbox('Done!', 'Success','help');
            end
        end

        % Callback function
        function DeletesegmentedfluidButtonValueChanged(app, event)
            value = app.DeletesegmentedfluidButton.Value;

            app.deletingFluid = value;
        end

        % Selection changed function: FluidcorrectionButtonGroup
        function FluidcorrectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.FluidcorrectionButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text,'Delete fluid') == 1
                app.deletingFluid = ~app.deletingFluid;
                app.addingFluid = false;
            elseif strcmp(selectedButton.Text,'Add fluid') == 1
                app.deletingFluid = false;
                app.addingFluid = true;
            end

        end

        % Value changed function: DELETEfluidButton
        function DELETEfluidButtonValueChanged(app, event)
            value = app.DELETEfluidButton.Value;

            app.deletingFluid = value;
        end

        % Button pushed function: QIButton
        function QIButtonPushed(app, event)

            % app.pathToOCTScan = 'D:\Licenta\Datasets\OCTDL\OCTDL\ERM\';
            % % app.pathToOCTScan = 'D:\Licenta\Datasets\date_31_ian\Pacient_1\OD\Visit_1\';
            % app.OCTScanName = 'erm_99';
            % % app.OCTScanName = 'A910D10';
            % app.OCTScanExt = '.jpg';
            % % Load the image
            % img = imread(strcat(app.pathToOCTScan, app.OCTScanName, app.OCTScanExt));

            % disp(strcat(app.pathToOCTScan, app.OCTScanName, app.OCTScanExt));
            % % img = double(img); % Convert to double for calculations
            % 
            % 
            % % Convert to grayscale if necessary
            % if size(img, 3) == 3
            %     img = rgb2gray(img);
            % end
            % 
            % % Identify and mask out pixels with intensities 0 and 255
            % mask = (img > 50) & (img < 255);
            % 
            % % disp(img);
            % % figure;
            % % imshow(mask); % Cast to uint8 for display
            % % title('Mask Image');
            % 
            % % Keep only the relevant region
            % % relevant_img = img .* uint8(mask);
            % relevant_img = SplitBregmanROF(double(img), .15, 0.001);
            % relevant_img = uint8(relevant_img);
            % 
            % % Display the processed image as uint8
            % % figure;
            % % imshow(uint8(relevant_img)); % Cast to uint8 for display
            % % title('Processed Image (0 and 255 Intensities Masked)');
            % 
            % % Calculate the histogram of the relevant pixels
            % % relevant_pixels = relevant_img(relevant_img > 50 & relevant_img < 255); % Exclude masked out pixels
            % relevant_pixels = relevant_img(relevant_img > min(relevant_img(:)) & relevant_img < max(relevant_img(:)));
            % [counts, binLocations] = imhist(relevant_pixels);
            % total_pixels = numel(relevant_pixels);
            % 
            % % Calculate the cumulative distribution function (CDF)
            % cdf = cumsum(counts) / total_pixels;
            % 
            % % Determine the percentile values
            % Low = binLocations(find(cdf >= 0.01, 1)); % 1st percentile
            % Noise = binLocations(find(cdf >= 0.75, 1)); % 75th percentile
            % Saturation = binLocations(find(cdf >= 0.99, 1)); % 99th percentile
            % Middle = (Noise + Saturation) / 2; % Mean of Noise and Saturation
            % disp(['Low: ', num2str(Low), ' Noise: ', num2str(Noise), ' Saturation: ',num2str(Saturation),' Middle: ', num2str(Middle)]);
            % 
            % % Calculate Intensity Ratio (IR)
            % % signal_pixels = relevant_pixels(relevant_pixels > Noise);
            % % IR = mean(signal_pixels) / std(signal_pixels);
            % IR = (Saturation - Low)/Low * 100;
            % 
            % % Calculate Tissue Signal Ratio (TSR)
            % high_reflective_pixels = sum(relevant_pixels >= Middle & relevant_pixels <= Saturation);
            % low_reflective_pixels = sum(relevant_pixels >= Noise & relevant_pixels < Middle);
            % disp(['high_reflective_pixels: ', num2str(high_reflective_pixels)]);
            % disp(['low_reflective_pixels: ', num2str(low_reflective_pixels)]);
            % TSR = high_reflective_pixels / low_reflective_pixels;
            % 
            % disp(['IR: ', num2str(IR), ' TSR: ', num2str(TSR)]);
            % % Compute the Quality Index (QI)
            % QI = IR * TSR;
            % 
            % % Display the result
            % disp(['Quality Index (QI): ', num2str(QI)]);
            % 
            % entropy_value = entropy(img);
            % 
            % laplacian_filter = fspecial('laplacian', 0);
            % laplacian_image = imfilter(img, laplacian_filter);
            % laplacian_image = double(laplacian_image);
            % sharpness = var(laplacian_image(:));
            % 
            % brisque_score = brisque(img);
            % 
            % % Check the data type and value range
            % disp(['Data type of imread image: ', class(img)]);
            % disp(['Value range of imread image: ', num2str(min(img(:))), ' to ', num2str(max(img(:)))]);
            % 
            % % Convert to double
            % double_image = double(img);
            % 
            % % Check the data type and value range
            % disp(['Data type of double image: ', class(double_image)]);
            % disp(['Value range of double image: ', num2str(min(double_image(:))), ' to ', num2str(max(double_image(:)))]);
            % %
            % % double_img = double(img);
            % 
            % denoised_image = SplitBregmanROF(double_image, .15, 0.001); 
            % 
            % 
            % disp(['Data type of denoised image: ', class(denoised_image)]);
            % disp(['Value range of denoise image: ', num2str(min(denoised_image(:))), ' to ', num2str(max(denoised_image(:)))]);
            % 
            % psnr_value = psnr(mat2gray(denoised_image), mat2gray(double_image));
            % 
            % % disp(denoised_image);
            % 
            % ssim_value = ssim(double_image, denoised_image);
            % 
            % disp(['Entropy: ', num2str(entropy_value)]);
            % disp(['Sharpness: ', num2str(sharpness)]);
            % disp(['Brisque score: ', num2str(brisque_score)]);
            % disp(['PSNR: ', num2str(psnr_value)]);
            % disp(['SSIM: ', num2str(ssim_value)]);
            % 
            % close all;
            % figure;
            % subplot(2,2,1);
            % imagesc(double_image);
            % colormap(gray);
            % title('Original');
            % 
            % % subplot(2,2,2);
            % % imagesc(noisy);
            % % colormap(gray);
            % % title('noisy');
            % 
            % subplot(2,2,3);
            % imagesc(denoised_image);
            % colormap(jet);
            % title('denoised');
            % 
            % subplot(2,2,4);
            % imagesc(double_image-denoised_image);
            % % imagesc(noisy-clean);
            % colormap(gray);
            % title('difference');

            % Set the path and filename for the OCT scan
            app.pathToOCTScan = 'D:\Licenta\Datasets\OCTDL\OCTDL\ERM\';
            app.OCTScanName = 'erm_99';
            app.OCTScanExt = '.jpg';
            % app.pathToOCTScan = 'D:\Licenta\Datasets\date_31_ian\Pacient_1\OD\Visit_1\';
            % app.OCTScanName = 'A910D10';
            
            % Load the image
            imgPath = strcat(app.pathToOCTScan, app.OCTScanName, app.OCTScanExt);
            img = imread(imgPath);
            disp(imgPath);
            
            % Convert to grayscale if necessary
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
            
            % Identify and mask out pixels with intensities below 50 and above 255
            % mask = (img > 50) & (img < 255);
            
            % Apply the Split Bregman ROF denoising method
            relevant_img = SplitBregmanROF(double(img), .15, 0.001);
            relevant_img = uint8(relevant_img);
            
            % Calculate the histogram of the relevant pixels
            relevant_pixels = relevant_img(relevant_img > min(relevant_img(:)) & relevant_img < max(relevant_img(:)));
            [counts, binLocations] = imhist(relevant_pixels);
            total_pixels = numel(relevant_pixels);
            
            % Calculate the cumulative distribution function (CDF)
            cdf = cumsum(counts) / total_pixels;
            
            % Determine the percentile values
            Low = binLocations(find(cdf >= 0.01, 1)); % 1st percentile
            Noise = binLocations(find(cdf >= 0.75, 1)); % 75th percentile
            Saturation = binLocations(find(cdf >= 0.99, 1)); % 99th percentile
            Middle = (Noise + Saturation) / 2; % Mean of Noise and Saturation
            disp(['Low: ', num2str(Low), ' Noise: ', num2str(Noise), ' Saturation: ', num2str(Saturation), ' Middle: ', num2str(Middle)]);
            
            % Calculate Intensity Ratio (IR)
            IR = (Saturation - Low) / Low * 100;
            
            % Calculate Tissue Signal Ratio (TSR)
            high_reflective_pixels = sum(relevant_pixels >= Middle & relevant_pixels <= Saturation);
            low_reflective_pixels = sum(relevant_pixels >= Noise & relevant_pixels < Middle);
            disp(['high_reflective_pixels: ', num2str(high_reflective_pixels)]);
            disp(['low_reflective_pixels: ', num2str(low_reflective_pixels)]);
            TSR = high_reflective_pixels / low_reflective_pixels;
            
            disp(['IR: ', num2str(IR), ' TSR: ', num2str(TSR)]);
            
            % Compute the Quality Index (QI)
            QI = IR * TSR;
            disp(['Quality Index (QI): ', num2str(QI)]);
            
            % Calculate entropy
            entropy_value = entropy(img);
            
            % Calculate sharpness using Laplacian variance
            laplacian_filter = fspecial('laplacian', 0);
            laplacian_image = imfilter(img, laplacian_filter);
            laplacian_image = double(laplacian_image);
            sharpness = var(laplacian_image(:));
            
            % Calculate BRISQUE score
            brisque_score = brisque(img);
            
            % Display data type and value range of the original image
            % disp(['Data type of imread image: ', class(img)]);
            % disp(['Value range of imread image: ', num2str(min(img(:))), ' to ', num2str(max(img(:)))]);
            
            % Convert to double for processing
            double_image = double(img); 
            
            % Display data type and value range of the double image
            % disp(['Data type of double image: ', class(double_image)]);
            % disp(['Value range of double image: ', num2str(min(double_image(:))), ' to ', num2str(max(double_image(:)))]);
            
            % Denoise the image using Split Bregman ROF
            denoised_image = SplitBregmanROF(double_image, .15, 0.001);
            
            % Display data type and value range of the denoised image
            % disp(['Data type of denoised image: ', class(denoised_image)]);
            % disp(['Value range of denoised image: ', num2str(min(denoised_image(:))), ' to ', num2str(max(denoised_image(:)))]);
            
            % Normalize images for PSNR calculation
            psnr_value = psnr(mat2gray(denoised_image), mat2gray(double_image));
            
            % Calculate SSIM
            ssim_value = ssim(double_image, denoised_image);
            
            % Display the calculated metrics
            disp(['Entropy: ', num2str(entropy_value)]);
            disp(['Sharpness: ', num2str(sharpness)]);
            disp(['Brisque score: ', num2str(brisque_score)]);
            disp(['PSNR: ', num2str(psnr_value)]);
            disp(['SSIM: ', num2str(ssim_value)]);
            
            % Display the images
            figure;
            subplot(2,2,1);
            imshow(double_image, []);
            colormap(gray);
            title('Original');
            
            subplot(2,2,3);
            imshow(denoised_image, []);
            colormap(jet);
            title('Denoised');
            
            subplot(2,2,4);
            imshow(double_image - denoised_image, []);
            colormap(gray);
            title('Difference');

        end

        % Button pushed function: AnisotropicButton
        function AnisotropicButtonPushed(app, event)
             app.pathToOCTScan = 'D:\Licenta\Datasets\OCTDL\OCTDL\ERM\';
            app.OCTScanName = 'erm_99';
            app.OCTScanExt = '.jpg';
            % app.pathToOCTScan = 'D:\Licenta\Datasets\date_31_ian\Pacient_1\OD\Visit_1\';
            % app.OCTScanName = 'A910D10';
            
            % Load the image
            imgPath = strcat(app.pathToOCTScan, app.OCTScanName, app.OCTScanExt);
            img = imread(imgPath);

            num_iter = 10;                % Number of iterations
            kappa = 20;                   % Conductance parameter
            lambda = 0.15;                % Integration constant
            
            output = anisotropic_diffusion(img, num_iter, kappa, lambda);
            
            % Display the original and the filtered image
            figure;
            subplot(1, 2, 1);
            imshow(img, []);
            title('Original Image');
            
            subplot(1, 2, 2);
            imshow(uint8(output), []);
            title('Filtered Image');
        end

        % Button pushed function: WaveletButton
        function WaveletButtonPushed(app, event)
            % Example usage
            app.pathToOCTScan = 'D:\Licenta\Datasets\OCTDL\OCTDL\ERM\';
            app.OCTScanName = 'erm_99';
            app.OCTScanExt = '.jpg';
            % app.pathToOCTScan = 'D:\Licenta\Datasets\date_31_ian\Pacient_1\OD\Visit_1\';
            % app.OCTScanName = 'A910D10';
            
            % Load the image
            imgPath = strcat(app.pathToOCTScan, app.OCTScanName, app.OCTScanExt);
            img = imread(imgPath);

            wavelet = 'sym8';                      % Type of wavelet
            level = 4;                             % Level of decomposition
            K = 4;                                 % Parameter to control thresholding
            
            denoised_img = wavelet_diffusion(img, wavelet, level, K);
            
            % Display the original and the filtered image
            figure;
            subplot(1, 2, 1);
            imshow(img, []);
            title('Original Image');
            
            subplot(1, 2, 2);
            imshow(uint8(denoised_img), []);
            title('Filtered Image');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataPanel = uipanel(app.LeftPanel);
            app.LoadDataPanel.Title = 'Load Data';
            app.LoadButton_2 = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadNewXMLPushed, true);
            app.LoadButton_2.Text = 'Load new XML';
            app.LoadButton_3 = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadSegmentedXMLPushed, true);
            app.LoadButton_3.Text = 'Load segmented XML';
            app.LoadButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load single OCT Scan';
            app.LoadlayersButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadlayersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlayersButtonPushed, true);
            app.LoadlayersButton.Visible = 'off';
            app.LoadlayersButton.Text = 'Load layers';
            app.PresegmentationBlockPanel = uipanel(app.LeftPanel);
            app.PresegmentationBlockPanel.Title = 'Pre segmentation Block';
            app.AlignOCTButton = uibutton(app.PresegmentationBlockPanel, 'push');
            app.AlignOCTButton.ButtonPushedFcn = createCallbackFcn(app, @AlignOCTButtonPushed, true);
            app.AlignOCTButton.Text = 'Align OCT';
            app.ConfiguresegmentationButton = uibutton(app.PresegmentationBlockPanel, 'push');
            app.ConfiguresegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfiguresegmentationButtonPushed, true);
            app.ConfiguresegmentationButton.Text = 'Configure segmentation';
            app.QIButton = uibutton(app.PresegmentationBlockPanel, 'push');
            app.QIButton.ButtonPushedFcn = createCallbackFcn(app, @QIButtonPushed, true);
            app.QIButton.Text = 'QI';
            app.AnisotropicButton = uibutton(app.PresegmentationBlockPanel, 'push');
            app.AnisotropicButton.ButtonPushedFcn = createCallbackFcn(app, @AnisotropicButtonPushed, true);
            app.AnisotropicButton.Text = 'Anisotropic';
            app.WaveletButton = uibutton(app.PresegmentationBlockPanel, 'push');
            app.WaveletButton.ButtonPushedFcn = createCallbackFcn(app, @WaveletButtonPushed, true);
            app.WaveletButton.Text = 'Wavelet';
            app.SegmentationBlockPanel = uipanel(app.LeftPanel);
            app.SegmentationBlockPanel.Title = 'Segmentation Block';
            app.DrusenButton = uibutton(app.SegmentationBlockPanel, 'push');
            app.DrusenButton.ButtonPushedFcn = createCallbackFcn(app, @DrusenButtonPushed, true);
            app.DrusenButton.Text = 'Drusen';
            app.NormalButton = uibutton(app.SegmentationBlockPanel, 'push');
            app.NormalButton.ButtonPushedFcn = createCallbackFcn(app, @NormalButtonPushed, true);
            app.NormalButton.Text = 'Normal';
            app.FluidButton = uibutton(app.SegmentationBlockPanel, 'push');
            app.FluidButton.ButtonPushedFcn = createCallbackFcn(app, @FluidButtonPushed, true);
            app.FluidButton.Text = 'Fluid';
            app.FluidcorrectionButtonGroup = uibuttongroup(app.LeftPanel);
            app.FluidcorrectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FluidcorrectionButtonGroupSelectionChanged, true);
            app.FluidcorrectionButtonGroup.Title = 'Fluid correction';
            app.DeletefluidButton = uitogglebutton(app.FluidcorrectionButtonGroup);
            app.DeletefluidButton.Visible = 'off';
            app.DeletefluidButton.Text = 'Delete fluid';
            app.DeletefluidButton.Value = true;
            app.DELETEfluidButton = uibutton(app.FluidcorrectionButtonGroup, 'state');
            app.DELETEfluidButton.ValueChangedFcn = createCallbackFcn(app, @DELETEfluidButtonValueChanged, true);
            app.DELETEfluidButton.Text = 'DELETE fluid';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ImageAxes = uiaxes(app.CenterPanel);
            title(app.ImageAxes, 'Images')
            app.Panel = uipanel(app.CenterPanel);
            app.NextSaveButton = uibutton(app.Panel, 'push');
            app.NextSaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNextImagePushed, true);
            app.NextSaveButton.Text = 'Next & Save';
            app.PreviousSaveButton = uibutton(app.Panel, 'push');
            app.PreviousSaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousImagePushed, true);
            app.PreviousSaveButton.Text = 'Previous & Save';
            app.NoOCTScanloadedLabel = uilabel(app.Panel);
            app.NoOCTScanloadedLabel.Text = 'No OCT Scan loaded';
            app.RightPanel = uipanel(app.GridLayout);
            app.PostsegmentationBlockPanel = uipanel(app.RightPanel);
            app.PostsegmentationBlockPanel.Title = 'Post segmentation Block';
            app.SaveButton = uibutton(app.PostsegmentationBlockPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExportthicknessButton = uibutton(app.PostsegmentationBlockPanel, 'push');
            app.ExportthicknessButton.ButtonPushedFcn = createCallbackFcn(app, @ExportthicknessButtonPushed, true);
            app.ExportthicknessButton.Text = 'Export thickness';
            app.ManualsegmentationPanel = uipanel(app.RightPanel);
            app.ManualsegmentationPanel.Title = 'Manual segmentation';
            app.EDITButtonGroup = uibuttongroup(app.ManualsegmentationPanel);
            app.EDITButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EDITButtonGroupSelectionChanged, true);
            app.EDITButtonGroup.Title = 'EDIT';
            app.IMLButton = uiradiobutton(app.EDITButtonGroup);
            app.IMLButton.Text = 'IML';
            app.IMLButton.Value = true;
            app.RNFLButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.RNFLButton_2.Text = 'RNFL';
            app.IPLINLButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.IPLINLButton_2.Text = 'IPL-INL';
            app.INLOPLButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.INLOPLButton_2.Text = 'INL-OPL';
            app.OPLOButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.OPLOButton_2.Text = 'OPLO';
            app.ONLISButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.ONLISButton_2.Text = 'ONL-IS';
            app.ISOSButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.ISOSButton_2.Text = 'IS-OS';
            app.OSRPEButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.OSRPEButton_2.Text = 'OS-RPE';
            app.RPEButton_4 = uiradiobutton(app.EDITButtonGroup);
            app.RPEButton_4.Text = 'RPE';
            app.IMLButton_2 = uibutton(app.ManualsegmentationPanel, 'state');
            app.IMLButton_2.ValueChangedFcn = createCallbackFcn(app, @IMLButton_2ValueChanged, true);
            app.IMLButton_2.Text = 'IML';
            app.RNFLButton = uibutton(app.ManualsegmentationPanel, 'state');
            app.RNFLButton.ValueChangedFcn = createCallbackFcn(app, @RNFLButtonValueChanged, true);
            app.RNFLButton.Text = 'RNFL';
            app.ONLISButton = uibutton(app.ManualsegmentationPanel, 'state');
            app.ONLISButton.ValueChangedFcn = createCallbackFcn(app, @ONLISButtonValueChanged, true);
            app.ONLISButton.Text = 'ONL-IS';
            app.IPLINLButton = uibutton(app.ManualsegmentationPanel, 'state');
            app.IPLINLButton.ValueChangedFcn = createCallbackFcn(app, @IPLINLButtonValueChanged, true);
            app.IPLINLButton.Text = 'IPL-INL';
            app.ISOSButton = uibutton(app.ManualsegmentationPanel, 'state');
            app.ISOSButton.ValueChangedFcn = createCallbackFcn(app, @ISOSButtonValueChanged, true);
            app.ISOSButton.Text = 'IS-OS';
            app.INLOPLButton = uibutton(app.ManualsegmentationPanel, 'state');
            app.INLOPLButton.ValueChangedFcn = createCallbackFcn(app, @INLOPLButtonValueChanged, true);
            app.INLOPLButton.Text = 'INL-OPL';
            app.OSRPEButton = uibutton(app.ManualsegmentationPanel, 'state');
            app.OSRPEButton.ValueChangedFcn = createCallbackFcn(app, @OSRPEButtonValueChanged, true);
            app.OSRPEButton.Text = 'OS-RPE';
            app.OPLOButton = uibutton(app.ManualsegmentationPanel, 'state');
            app.OPLOButton.ValueChangedFcn = createCallbackFcn(app, @OPLOButtonValueChanged, true);
            app.OPLOButton.Text = 'OPLO';
            app.RPEButton_3 = uibutton(app.ManualsegmentationPanel, 'state');
            app.RPEButton_3.ValueChangedFcn = createCallbackFcn(app, @RPEButton_3ValueChanged, true);
            app.RPEButton_3.Text = 'RPE';
            app.SHOWLabel = uilabel(app.ManualsegmentationPanel);
            app.SHOWLabel.Text = 'SHOW';
            app.EnterManualsegmentationmodeButton = uibutton(app.ManualsegmentationPanel, 'state');
            app.EnterManualsegmentationmodeButton.ValueChangedFcn = createCallbackFcn(app, @EnterManualsegmentationmodeButtonValueChanged, true);
            app.EnterManualsegmentationmodeButton.Text = 'Enter Manual segmentation mode';
            app.RevertchangesButton = uibutton(app.ManualsegmentationPanel, 'push');
            app.RevertchangesButton.ButtonPushedFcn = createCallbackFcn(app, @RevertchangesButtonPushed, true);
            app.RevertchangesButton.Text = 'Revert changes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OctSegmentationGUI_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end