classdef OctSegmentationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ImageAxes                matlab.ui.control.UIAxes
        LoadDataPanel            matlab.ui.container.Panel
        LoadButton_2             matlab.ui.control.Button
        LoadButton_3             matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        LoadlayersButton         matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        NextSaveButton           matlab.ui.control.Button
        PreviousSaveButton       matlab.ui.control.Button
        NoOCTScanloadedLabel     matlab.ui.control.Label
        SegmentationPanel        matlab.ui.container.Panel
        StartsegmentationButton  matlab.ui.control.Button
        RevertchangesButton      matlab.ui.control.Button
        EnterEDITmodeButton      matlab.ui.control.StateButton
        PostsegmentationPanel    matlab.ui.container.Panel
        SaveButton               matlab.ui.control.Button
        ExportthicknessButton    matlab.ui.control.Button
        LayersPanel              matlab.ui.container.Panel
        EDITButtonGroup          matlab.ui.container.ButtonGroup
        IMLButton                matlab.ui.control.RadioButton
        RNFLButton_2             matlab.ui.control.RadioButton
        IPLINLButton_2           matlab.ui.control.RadioButton
        INLOPLButton_2           matlab.ui.control.RadioButton
        OPLOButton_2             matlab.ui.control.RadioButton
        ONLISButton_2            matlab.ui.control.RadioButton
        ISOSButton_2             matlab.ui.control.RadioButton
        OSRPEButton_2            matlab.ui.control.RadioButton
        RPEButton_4              matlab.ui.control.RadioButton
        IMLButton_2              matlab.ui.control.StateButton
        RNFLButton               matlab.ui.control.StateButton
        ONLISButton              matlab.ui.control.StateButton
        IPLINLButton             matlab.ui.control.StateButton
        ISOSButton               matlab.ui.control.StateButton
        INLOPLButton             matlab.ui.control.StateButton
        OSRPEButton              matlab.ui.control.StateButton
        OPLOButton               matlab.ui.control.StateButton
        RPEButton_3              matlab.ui.control.StateButton
        SHOWLabel                matlab.ui.control.Label
        PresegmentationPanel     matlab.ui.container.Panel
        AlignOCTscansButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        Layers = []; % rendered layers
        RPECH_layer; % Boolean variable to render the layer or not
        OSRPE_layer;
        ISOS_layer;
        ONLIS_layer;
        OPLO_layer;
        INLOPL_layer;
        IPLINL_layer;
        RNFL_layer;
        ILM_layer;
        
        pathToOCTScan;
        pathToLayers;
        OCTScanName;
        OCTScanExt;
        FileNames = [];
        Files = [];
        scanIndex;
        SegmentedImages = [];
        alreadySegmented;
        inEditMode;
    end
    
    
    methods (Access = private)
        
        
        function updateimage(app,imagefile)
            
            global layersLoaded;
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            end
            
            global imgSize;
            
            imgSize = size(im);
            
            layersLoaded = false;
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    
                    displayedImg = imagesc(app.ImageAxes,im);
                    hold(app.ImageAxes);
                    
                    
                case 3
                    % Display the truecolor image
                    displayedImg = imagesc(app.ImageAxes,im);
                    %                     hold on;
                    
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            
            
        end
        
        function loadLayers(app, dname, fname, fileIndex)
            global imgSize;
            %             disp(dname);
            if (ischar(dname))
  
                if isfolder(dname)
                    
                    cd(dname);
         
                    app.SegmentedImages = [app.SegmentedImages, load([fname '.mat'])];
%                     app.SegmentedImages(fileIndex).Layers = ;
                    disp(app.SegmentedImages);
                    cd('../');
                    
                    app.pathToLayers = dname;
                    
                end
            end
        end


        function reloadLayers(app)
            global layersLoaded;
            global layerToEdit;
            global layerNameToEdit;
            
            
            app.Layers = app.SegmentedImages(app.scanIndex).Layers;


            if layersLoaded == true
                set(app.ImageAxes.Children(1), 'Ydata', app.Layers(1,:,:));
                set(app.ImageAxes.Children(2), 'Ydata', app.Layers(2,:,:));
                set(app.ImageAxes.Children(3), 'Ydata', app.Layers(3,:,:));
                set(app.ImageAxes.Children(4), 'Ydata', app.Layers(4,:,:));
                set(app.ImageAxes.Children(5), 'Ydata', app.Layers(5,:,:));
                set(app.ImageAxes.Children(6), 'Ydata', app.Layers(6,:,:));
                set(app.ImageAxes.Children(7), 'Ydata', app.Layers(7,:,:));
                set(app.ImageAxes.Children(8), 'Ydata', app.Layers(8,:,:));
                set(app.ImageAxes.Children(9), 'Ydata', app.Layers(9,:,:));
                
                app.EDITButtonGroup.SelectedObject = app.EDITButtonGroup.Buttons(1);
                
            else
                hold(app.ImageAxes);
                
                plot(app.ImageAxes,app.Layers(9,:,:),'-r', 'LineWidth',2);
                plot(app.ImageAxes,app.Layers(8,:,:),'-b', 'LineWidth',2);
                plot(app.ImageAxes,app.Layers(7,:,:),'-g', 'LineWidth',2);
                plot(app.ImageAxes,app.Layers(6,:,:),'-y', 'LineWidth',2);
                plot(app.ImageAxes,app.Layers(5,:,:),'-c', 'LineWidth',2);
                plot(app.ImageAxes,app.Layers(4,:,:),'-m', 'LineWidth',2);
                plot(app.ImageAxes,app.Layers(3,:,:),'-y', 'LineWidth',2);
                plot(app.ImageAxes,app.Layers(2,:,:),'-g', 'LineWidth',2);
                plot(app.ImageAxes,app.Layers(1,:,:),'-r', 'LineWidth',2);
                
                hold(app.ImageAxes);
            end
            
            
            layersLoaded = true;


            app.RPECH_layer = true;
            app.OSRPE_layer = true;
            app.ISOS_layer = true;
            app.ONLIS_layer = true;
            app.OPLO_layer = true;
            app.INLOPL_layer = true;
            app.IPLINL_layer = true;
            app.RNFL_layer = true;
            app.ILM_layer = true;
            
            set(app.IMLButton_2,'Enable','on');
            set(app.RPEButton_3,'Enable','on');
            set(app.OPLOButton,'Enable','on');
            set(app.OSRPEButton,'Enable','on');
            set(app.IPLINLButton,'Enable','on');
            set(app.INLOPLButton,'Enable','on');
            set(app.ISOSButton,'Enable','on');
            set(app.ONLISButton,'Enable','on');
            set(app.RNFLButton,'Enable','on');
            
            
            layerToEdit = app.Layers(1,:,:);
            layerNameToEdit = 'IML';
        end
        
        function saveSegmentation(app)
            global imgSize;
            
            Y = 1:imgSize(2);
            [~,n] = size(app.SegmentedImages);
         
            for k = 1:n
                segmentedImage = app.SegmentedImages(k);
                disp(k);
                [p,f,~] = extractInfoFromPath(app, k);
                
                save([p f '.mat'],'-struct', 'segmentedImage');
                disp('===============================');
                disp([p f '.mat']);
                disp('===============================');
            end
            %             img = app.ImageAxes;
            %
            %             filename = [layersFolder '_segmentation.tif'];
            %             disp(filename);
            %             exportgraphics(img, filename );
        end
        
        function exportThickness(app)
            
            RPECH_OSRPE = app.Layers(9,:,:) - app.Layers(8,:,:);
            OSRPE_ISOS = app.Layers(8,:,:) - app.Layers(7,:,:);
            ISOS_ONLIS =app.Layers(7,:,:) - app.Layers(6,:,:);
            ONLIS_OPLO = app.Layers(6,:,:) - app.Layers(5,:,:);
            OPLO_INLOPL =app.Layers(5,:,:) - app.Layers(4,:,:);
            INLOPL_IPLINL = app.Layers(4,:,:) - app.Layers(3,:,:);
            IPLINL_RNFL = app.Layers(3,:,:) - app.Layers(2,:,:);
            RNFL_IML = app.Layers(2,:,:) - app.Layers(1,:,:);
            
            layers = [
                RPECH_OSRPE;
                OSRPE_ISOS;
                ISOS_ONLIS;
                ONLIS_OPLO;
                OPLO_INLOPL;
                INLOPL_IPLINL;
                IPLINL_RNFL;
                RNFL_IML;
                ];
            
            
            %             cd([PathName subFolderName '/']);
            %             disp([app.pathToOCTScan  app.OCTScanName '_thickness.csv']);
            writematrix(layers, [app.pathToOCTScan  app.OCTScanName '_thickness.csv']);
        end
        
        function loadFromXml(app, path, name, ext)
            if strcmp(ext,'.xml') == 1
                docNode = xmlread(strcat(path, [name ext]));
                data = docNode.getElementsByTagName('ExamURL');
                im_slo = char(data.item(0).getFirstChild.getData);
                
                loc = strfind(im_slo,  '/');
                im_slo(1:loc(end)) = [];
                
                imread([path im_slo]);
                
                no_of_files = data.getLength - 1;
                
                %                 subFolderName =  '';
                %                 WorkDir = [path subFolderName];
                %
                %                 matrix = ones(no_of_files, 5);
                
                for k = 1:no_of_files
                    %                     docNode = xmlread(strcat(path, [name ext]));
                    data = docNode.getElementsByTagName('ExamURL');
                    im_oct = char(data.item(k).getFirstChild.getData);
                    loc = strfind(im_oct,  '/');
                    im_oct(1:loc(end)) = [];
                    %                     imgName = [path im_oct];
                    [~, fName1, fExt1] = fileparts(im_oct);
                    
                    app.FileNames = [app.FileNames; [path, fName1, fExt1]];
                    
                    if app.alreadySegmented == true
                        [p,f,~] = extractInfoFromPath(app, k);
                        loadLayers(app, p, f, k);
                    end
                    
                end
                
                loadImages(app);
            end
        end
        
        
        function loadImages(app)
            
            app.scanIndex = 1;
            updateimage(app, app.FileNames(1,:,:));
            
            updateNumLabel(app);
            if app.alreadySegmented == true
                reloadLayers(app);
                %                 layersFolder = split(app.FileNames(1,:,:),'.');
                %                 layersFolder = char(strcat(layersFolder(1),'/'));
                %                 loadLayers(app, layersFolder, 1);
            end
        end
        
        function updateNumLabel(app)
            [m,~] = size(app.FileNames);
            app.NoOCTScanloadedLabel.Text = [num2str(app.scanIndex) '/' num2str(m)];
            drawnow;
        end
        
        function resetVariables(app)
            app.Layers = [];
            app.FileNames = [];
            app.SegmentedImages = [];
            app.inEditMode = false;
        end


        function [path,name,ext] = extractInfoFromPath(app, index)
            layersFolder = split(app.FileNames(index,:,:),'/');
            [m,~] = size(layersFolder);
            fileName = char(layersFolder(m));
            layersFolder = char(join(layersFolder(1:m-1)));
            layersFolder = strrep(layersFolder, ' ','/');
            layersFolder = [layersFolder '/'];
            fileName = split(fileName, '.');
            ext = ['.' char(fileName(2))];
            fileName = char(fileName(1));
            
            path = layersFolder;
            name = fileName;
      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global layersLoaded;
            layersLoaded = false;
            
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            disableDefaultInteractivity(app.ImageAxes);
            
            
            app.RPECH_layer = false;
            
            app.OSRPE_layer = false;
            
            app.ISOS_layer = false;
            
            app.ONLIS_layer = false;
            
            app.OPLO_layer = false;
            
            app.INLOPL_layer = false;
            
            app.IPLINL_layer = false;
            
            app.RNFL_layer = false;
            
            app.ILM_layer = false;
            
            set(app.RPEButton_3,'Enable','off');
            set(app.IMLButton_2,'Enable','off');
            set(app.OPLOButton,'Enable','off');
            set(app.OSRPEButton,'Enable','off');
            set(app.IPLINLButton,'Enable','off');
            set(app.INLOPLButton,'Enable','off');
            set(app.ISOSButton,'Enable','off');
            set(app.ONLISButton,'Enable','off');
            set(app.RNFLButton,'Enable','off');


            % Update the image and histogramsA95C800
            app.pathToOCTScan = '/media/sergiu/Shared/Licenta/Interface/OCTSegExamples/date_31_ian/Pacient_5/OD/Visit_2/';
            app.OCTScanName = 'EABF04D0';
            app.OCTScanExt = '.tif';
            %             updateimage(app, [app.pathToOCTScan app.OCTScanName app.OCTScanExt]);
            %             loadLayers(app, [app.pathToOCTScan app.OCTScanName], 0);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file, path] = uigetfile(filterspec);
            [p, f, e] = fileparts([path file]);
            p = [p '/'];
            
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                app.pathToOCTScan = p;
                app.OCTScanName = f;
                app.OCTScanExt = e;
                fname = [p f e];
                updateimage(app, fname);
                
                loadLayers(app, [p f], 0);
            end
        end

        % Button pushed function: LoadlayersButton
        function LoadlayersButtonPushed(app, event)
            %             dname = uigetdir('/media/sergiu/Shared/Licenta/Tools/OCTSegExamples/Visit_1/');
            dname = uigetdir();
            
            
            loadLayers(app, dname,0);
        end

        % Selection changed function: EDITButtonGroup
        function EDITButtonGroupSelectionChanged(app, event)
            selectedButton = app.EDITButtonGroup.SelectedObject;
            
            global layerToEdit;
            global layerNameToEdit;
            
            if strcmp(selectedButton.Text,'IML') == 1
                layerToEdit = app.Layers(1,:,:);
                layerNameToEdit = 'IML';
            elseif strcmp(selectedButton.Text, 'RNFL') == 1
                layerToEdit = app.Layers(2,:,:);
                layerNameToEdit = 'RNFL';
            elseif strcmp(selectedButton.Text, 'IPL-INL') == 1
                layerToEdit = app.Layers(3,:,:);
                layerNameToEdit = 'IPLINL';
            elseif strcmp(selectedButton.Text, 'INL-OPL') == 1
                layerToEdit = app.Layers(4,:,:);
                layerNameToEdit = 'INLOPL';
            elseif strcmp(selectedButton.Text, 'OPLO') == 1
                layerToEdit = app.Layers(5,:,:);
                layerNameToEdit = 'OPLO';
            elseif strcmp(selectedButton.Text, 'ONL-IS') == 1
                layerToEdit = app.Layers(6,:,:);
                layerNameToEdit = 'ONLIS';
            elseif strcmp(selectedButton.Text, 'IS-OS') == 1
                layerToEdit = app.Layers(7,:,:);
                layerNameToEdit = 'ISOS';
            elseif strcmp(selectedButton.Text, 'OS-RPE') == 1
                layerToEdit = app.Layers(8,:,:);
                layerNameToEdit = 'OSRPE';
            elseif strcmp(selectedButton.Text, 'RPE') == 1
                layerToEdit = app.Layers(9,:,:);
                layerNameToEdit = 'RPECH';
            end
            
        end

        % Value changed function: IMLButton_2
        function IMLButton_2ValueChanged(app, event)
%             value = app.IMLButton_2.Value;
            
            if app.ILM_layer == false
                set(app.ImageAxes.Children(1),'visible','on');
                app.ILM_layer = true;
            else
                set(app.ImageAxes.Children(1),'visible','off');
                app.ILM_layer = false;
            end
        end

        % Value changed function: RNFLButton
        function RNFLButtonValueChanged(app, event)
%             value = app.RNFLButton.Value;
            %              global RNFL_layer;
            
            if app.RNFL_layer == false
                set(app.ImageAxes.Children(2),'visible','on');
                app.RNFL_layer = true;
            else
                set(app.ImageAxes.Children(2),'visible','off');
                app.RNFL_layer = false;
            end
        end

        % Value changed function: IPLINLButton
        function IPLINLButtonValueChanged(app, event)
%             value = app.IPLINLButton.Value;
            %              global GCL_layer;
            
            if app.IPLINL_layer == false
                set(app.ImageAxes.Children(3),'visible','on');
                app.IPLINL_layer = true;
            else
                set(app.ImageAxes.Children(3),'visible','off');
                app.IPLINL_layer = false;
            end
        end

        % Value changed function: INLOPLButton
        function INLOPLButtonValueChanged(app, event)
%             value = app.INLOPLButton.Value;
            %              global IPL_layer;
            
            if app.INLOPL_layer == false
                set(app.ImageAxes.Children(4),'visible','on');
                app.INLOPL_layer = true;
            else
                set(app.ImageAxes.Children(4),'visible','off');
                app.INLOPL_layer = false;
            end
        end

        % Value changed function: OPLOButton
        function OPLOButtonValueChanged(app, event)
%             value = app.OPLOButton.Value;
            %              global INL_layer;
            
            if app.OPLO_layer == false
                set(app.ImageAxes.Children(5),'visible','on');
                app.OPLO_layer = true;
            else
                set(app.ImageAxes.Children(5),'visible','off');
                app.OPLO_layer = false;
            end
        end

        % Value changed function: ONLISButton
        function ONLISButtonValueChanged(app, event)
%             value = app.ONLISButton.Value;
            %              global OPL_layer;
            
            if app.ONLIS_layer == false
                set(app.ImageAxes.Children(6),'visible','on');
                app.ONLIS_layer = true;
            else
                set(app.ImageAxes.Children(6),'visible','off');
                app.ONLIS_layer = false;
            end
        end

        % Value changed function: ISOSButton
        function ISOSButtonValueChanged(app, event)
%             value = app.ISOSButton.Value;
            %              global ONL_layer;
            
            if app.ISOS_layer == false
                set(app.ImageAxes.Children(7),'visible','on');
                app.ISOS_layer = true;
            else
                set(app.ImageAxes.Children(7),'visible','off');
                app.ISOS_layer = false;
            end
        end

        % Value changed function: OSRPEButton
        function OSRPEButtonValueChanged(app, event)
%             value = app.OSRPEButton.Value;
            %              global IS_layer;
            
            if app.OSRPE_layer == false
                set(app.ImageAxes.Children(8),'visible','on');
                app.OSRPE_layer = true;
            else
                set(app.ImageAxes.Children(8),'visible','off');
                app.OSRPE_layer = false;
            end
        end

        % Value changed function: RPEButton_3
        function RPEButton_3ValueChanged(app, event)
%             value = app.RPEButton_3.Value;
%             global OS_layer;
            
            if app.RPECH_layer == false
                set(app.ImageAxes.Children(9),'visible','on');
                app.RPECH_layer = true;
            else
                set(app.ImageAxes.Children(9),'visible','off');
                app.RPECH_layer = false;
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            global mousePressed;
            global pointBF;
            global imgSize;
            global layersLoaded;
            
            
            currentPoint = app.ImageAxes.CurrentPoint;
            
            if layersLoaded == true && app.inEditMode==true && currentPoint(1) > 0 && currentPoint(3) > 0 && (currentPoint(4) < imgSize(1)) && currentPoint(2) < imgSize(2)
                pointBF = [round(currentPoint(1)), round(currentPoint(3))];
                
                mousePressed = true;
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            global mousePressed;
            global imgSize;
            global layerToEdit;
            global layerNameToEdit;
            
            global pointBF;
            
            switched = 0;
            currentPoint = app.ImageAxes.CurrentPoint;
            
            
            if mousePressed == true & currentPoint(1) > 0 & currentPoint(3) > 0 & (currentPoint(4) < imgSize(1)) & currentPoint(2) < imgSize(2)
                
                
                actPoint = [round(currentPoint(1)), round(currentPoint(3))];
                
                
                if actPoint(1) < pointBF(1)
                    temp = pointBF;
                    pointBF = actPoint;
                    actPoint = temp;
                    switched = 1;
                end
                
                
                diff = actPoint(2) - pointBF(2);
                diffY = actPoint(1) - pointBF(1);
                %                 updateColumns = pointBF(1):1:actPoint(1);
                
                disp(actPoint);
                disp(pointBF);
                if pointBF(1) == 1
                    updateColumns = pointBF(1):1:actPoint(1) + 1;
                elseif actPoint(1) == imgSize(2)
                    updateColumns = pointBF(1)-1:1:actPoint(1);
                else
                    updateColumns = pointBF(1)-1:1:actPoint(1) + 1;
                end
                
                if diffY == 0
                    for i = updateColumns
                        layerToEdit(i) = pointBF(2);
                    end
                else
                    for i = updateColumns
                        layerToEdit(i) = pointBF(2) + diff * ((i - pointBF(1)) / abs(diffY));
                    end
                end
                
                if switched == 0
                    pointBF = actPoint;
                end
                

%                 layerToEdit = layerToEdit;
                
                drawnow; pause(0.005);
                switch layerNameToEdit
                    case 'IML'
                        set(app.ImageAxes.Children(1), 'Ydata', layerToEdit);
                        app.Layers(1,:,:) = layerToEdit;
                    case 'RNFL'
                        set(app.ImageAxes.Children(2), 'Ydata', layerToEdit);
                        app.Layers(2,:,:) = layerToEdit;
                    case 'IPLINL'
                        set(app.ImageAxes.Children(3), 'Ydata', layerToEdit);
                        app.Layers(3,:,:) = layerToEdit;
                    case 'INLOPL'
                        set(app.ImageAxes.Children(4), 'Ydata', layerToEdit);
                        app.Layers(4,:,:) = layerToEdit;
                    case 'OPLO'
                        set(app.ImageAxes.Children(5), 'Ydata', layerToEdit);
                        app.Layers(5,:,:) = layerToEdit;
                    case 'ONLIS'
                        set(app.ImageAxes.Children(6), 'Ydata', layerToEdit);
                        app.Layers(6,:,:) = layerToEdit;
                    case 'ISOS'
                        set(app.ImageAxes.Children(7), 'Ydata', layerToEdit);
                        app.Layers(7,:,:) = layerToEdit;
                    case 'OSRPE'
                        set(app.ImageAxes.Children(8), 'Ydata', layerToEdit);
                        app.Layers(8,:,:) = layerToEdit;
                    otherwise
                        set(app.ImageAxes.Children(9), 'Ydata', layerToEdit);
                        app.Layers(9,:,:) = layerToEdit;
                end
                drawnow; pause(0.005);
            
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            global mousePressed;
            mousePressed = false;
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
%             verticalScrollAmount = event.VerticalScrollAmount;
%             verticalScrollCount = event.VerticalScrollCount;
            
        end

        % Button pushed function: RevertchangesButton
        function RevertchangesButtonPushed(app, event)
            reloadLayers(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveSegmentation(app);
            
            
        end

        % Button pushed function: ExportthicknessButton
        function ExportthicknessButtonPushed(app, event)
            exportThickness(app);


        end

        % Value changed function: EnterEDITmodeButton
        function EnterEDITmodeButtonValueChanged(app, event)
            value = app.EnterEDITmodeButton.Value;
            
           
            app.inEditMode = value;
        end

        % Button pushed function: LoadButton_2
        function LoadNewXMLPushed(app, event)
            resetVariables(app);
            
            % Display uigetfile dialog
            filterspec = {'*.xml;'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                [p, f, e] = fileparts([path file]);
                p = [p '/'];
                app.alreadySegmented = false;
                
                app.pathToOCTScan = p;
                app.OCTScanName = f;
                app.OCTScanExt = e;
               
                loadFromXml(app, p, f, e);
            end
        end

        % Button pushed function: LoadButton_3
        function LoadSegmentedXMLPushed(app, event)
            resetVariables(app);
            
            % Display uigetfile dialog
            filterspec = {'*.xml;'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                [p, f, e] = fileparts([path file]);
                p = [p '/'];
                
                app.alreadySegmented = true;
                
                app.pathToOCTScan = p;
                app.OCTScanName = f;
                app.OCTScanExt = e;

                loadFromXml(app, p, f, e);
            end
        end

        % Button pushed function: NextSaveButton
        function LoadNextImagePushed(app, event)
            [m,~] = size(app.FileNames);
            
            app.SegmentedImages(app.scanIndex).Layers = app.Layers;
            
            if app.scanIndex < m
                app.scanIndex = app.scanIndex + 1;
                image = app.FileNames(app.scanIndex,:, :);
                updateimage(app,image);
                
                updateNumLabel(app);
            elseif app.scanIndex == m
                app.scanIndex = 1;
                image = app.FileNames(app.scanIndex,:, :);
                updateimage(app,image);
                
                updateNumLabel(app);
            end
            
            if app.alreadySegmented == true
                
%                 [p,f,~] = extractInfoFromPath(app, app.scanIndex);
%                 loadLayers(app, p, f, app.scanIndex);
                reloadLayers(app);
            end
            
        end

        % Button pushed function: PreviousSaveButton
        function LoadPreviousImagePushed(app, event)
            [m,~] = size(app.FileNames);
            
            app.SegmentedImages(app.scanIndex).Layers = app.Layers;
            
            if app.scanIndex > 1
                app.scanIndex = app.scanIndex - 1;
                image = app.FileNames(app.scanIndex,:, :);
                updateimage(app,image);
                
                updateNumLabel(app);
            elseif app.scanIndex == 1
                app.scanIndex = m;
                image = app.FileNames(app.scanIndex,:, :);
                updateimage(app,image);
                
                updateNumLabel(app);
            end
            
            if app.alreadySegmented == true
%                 [p,f,~] = extractInfoFromPath(app, app.scanIndex);
%                 loadLayers(app, p, f, app.scanIndex);
                reloadLayers(app);
            end
        end

        % Button pushed function: StartsegmentationButton
        function StartsegmentationButtonPushed(app, event)
            [len,~] = size(app.FileNames);
            for k = 1:len
                layersFolder = split(app.FileNames(k,:,:),'/');
                [m,~] = size(layersFolder);
                fileName = char(layersFolder(m));
                layersFolder = char(join(layersFolder(1:m-1)));
                layersFolder = strrep(layersFolder, ' ','/');
                layersFolder = [layersFolder '/'];
                fileName = split(fileName, '.');
                ext = ['.' char(fileName(2))];
                fileName = char(fileName(1));
                
                subFolderName = '';
                
                segmentMultipleOCT(layersFolder, fileName, ext, subFolderName);
            end
        end

        % Button pushed function: AlignOCTscansButton
        function AlignOCTscansButtonPushed(app, event)
            [len,~] = size(app.FileNames);
            for k = 1:len
                
                im = curvCorrectionAMPAO(app.FileNames(k,:,:));
                disp(['Aligned ' app.FileNames(k,:,:)]);
                im = double(im);
                im = mat2gray(im);
%                 imshow(im);
%                 hold on;
                imwrite(im, app.FileNames(k,:,:));
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = 'Load Data';
            app.LoadButton_2 = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadNewXMLPushed, true);
            app.LoadButton_2.Text = 'Load new XML';
            app.LoadButton_3 = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadSegmentedXMLPushed, true);
            app.LoadButton_3.Text = 'Load segmented XML';
            app.LoadButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load single OCT Scan';
            app.LoadlayersButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadlayersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlayersButtonPushed, true);
            app.LoadlayersButton.Text = 'Load layers';
            app.Panel = uipanel(app.UIFigure);
            app.NextSaveButton = uibutton(app.Panel, 'push');
            app.NextSaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNextImagePushed, true);
            app.NextSaveButton.Text = 'Next & Save';
            app.PreviousSaveButton = uibutton(app.Panel, 'push');
            app.PreviousSaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousImagePushed, true);
            app.PreviousSaveButton.Text = 'Previous & Save';
            app.NoOCTScanloadedLabel = uilabel(app.Panel);
            app.NoOCTScanloadedLabel.Text = 'No OCT Scan loaded';
            app.SegmentationPanel = uipanel(app.UIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.StartsegmentationButton = uibutton(app.SegmentationPanel, 'push');
            app.StartsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsegmentationButtonPushed, true);
            app.StartsegmentationButton.Text = 'Start segmentation';
            app.RevertchangesButton = uibutton(app.SegmentationPanel, 'push');
            app.RevertchangesButton.ButtonPushedFcn = createCallbackFcn(app, @RevertchangesButtonPushed, true);
            app.RevertchangesButton.Text = 'Revert changes';
            app.EnterEDITmodeButton = uibutton(app.SegmentationPanel, 'state');
            app.EnterEDITmodeButton.ValueChangedFcn = createCallbackFcn(app, @EnterEDITmodeButtonValueChanged, true);
            app.EnterEDITmodeButton.Text = 'Enter EDIT mode';
            app.PostsegmentationPanel = uipanel(app.UIFigure);
            app.PostsegmentationPanel.Title = 'Post segmentation';
            app.SaveButton = uibutton(app.PostsegmentationPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExportthicknessButton = uibutton(app.PostsegmentationPanel, 'push');
            app.ExportthicknessButton.ButtonPushedFcn = createCallbackFcn(app, @ExportthicknessButtonPushed, true);
            app.ExportthicknessButton.Text = 'Export thickness';
            app.LayersPanel = uipanel(app.UIFigure);
            app.LayersPanel.Title = 'Layers';
            app.EDITButtonGroup = uibuttongroup(app.LayersPanel);
            app.EDITButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EDITButtonGroupSelectionChanged, true);
            app.EDITButtonGroup.Title = 'EDIT';
            app.IMLButton = uiradiobutton(app.EDITButtonGroup);
            app.IMLButton.Text = 'IML';
            app.IMLButton.Value = true;
            app.RNFLButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.RNFLButton_2.Text = 'RNFL';
            app.IPLINLButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.IPLINLButton_2.Text = 'IPL-INL';
            app.INLOPLButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.INLOPLButton_2.Text = 'INL-OPL';
            app.OPLOButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.OPLOButton_2.Text = 'OPLO';
            app.ONLISButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.ONLISButton_2.Text = 'ONL-IS';
            app.ISOSButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.ISOSButton_2.Text = 'IS-OS';
            app.OSRPEButton_2 = uiradiobutton(app.EDITButtonGroup);
            app.OSRPEButton_2.Text = 'OS-RPE';
            app.RPEButton_4 = uiradiobutton(app.EDITButtonGroup);
            app.RPEButton_4.Text = 'RPE';
            app.IMLButton_2 = uibutton(app.LayersPanel, 'state');
            app.IMLButton_2.ValueChangedFcn = createCallbackFcn(app, @IMLButton_2ValueChanged, true);
            app.IMLButton_2.Text = 'IML';
            app.RNFLButton = uibutton(app.LayersPanel, 'state');
            app.RNFLButton.ValueChangedFcn = createCallbackFcn(app, @RNFLButtonValueChanged, true);
            app.RNFLButton.Text = 'RNFL';
            app.ONLISButton = uibutton(app.LayersPanel, 'state');
            app.ONLISButton.ValueChangedFcn = createCallbackFcn(app, @ONLISButtonValueChanged, true);
            app.ONLISButton.Text = 'ONL-IS';
            app.IPLINLButton = uibutton(app.LayersPanel, 'state');
            app.IPLINLButton.ValueChangedFcn = createCallbackFcn(app, @IPLINLButtonValueChanged, true);
            app.IPLINLButton.Text = 'IPL-INL';
            app.ISOSButton = uibutton(app.LayersPanel, 'state');
            app.ISOSButton.ValueChangedFcn = createCallbackFcn(app, @ISOSButtonValueChanged, true);
            app.ISOSButton.Text = 'IS-OS';
            app.INLOPLButton = uibutton(app.LayersPanel, 'state');
            app.INLOPLButton.ValueChangedFcn = createCallbackFcn(app, @INLOPLButtonValueChanged, true);
            app.INLOPLButton.Text = 'INL-OPL';
            app.OSRPEButton = uibutton(app.LayersPanel, 'state');
            app.OSRPEButton.ValueChangedFcn = createCallbackFcn(app, @OSRPEButtonValueChanged, true);
            app.OSRPEButton.Text = 'OS-RPE';
            app.OPLOButton = uibutton(app.LayersPanel, 'state');
            app.OPLOButton.ValueChangedFcn = createCallbackFcn(app, @OPLOButtonValueChanged, true);
            app.OPLOButton.Text = 'OPLO';
            app.RPEButton_3 = uibutton(app.LayersPanel, 'state');
            app.RPEButton_3.ValueChangedFcn = createCallbackFcn(app, @RPEButton_3ValueChanged, true);
            app.RPEButton_3.Text = 'RPE';
            app.SHOWLabel = uilabel(app.LayersPanel);
            app.SHOWLabel.Text = 'SHOW';
            app.PresegmentationPanel = uipanel(app.UIFigure);
            app.PresegmentationPanel.Title = 'Pre segmentation';
            app.AlignOCTscansButton = uibutton(app.PresegmentationPanel, 'push');
            app.AlignOCTscansButton.ButtonPushedFcn = createCallbackFcn(app, @AlignOCTscansButtonPushed, true);
            app.AlignOCTscansButton.Text = 'Align OCT scans';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OctSegmentationGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end