classdef updateParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UpdatetheparametersaccordingtoyourneedsLabel  matlab.ui.control.Label
        NinthlayerPanel                 matlab.ui.container.Panel
        Pixelsabove7thlayerEditFieldLabel  matlab.ui.control.Label
        Pixelsabove7thlayerEditField    matlab.ui.control.EditField
        SmoothnessEditFieldLabel        matlab.ui.control.Label
        SmoothnessEditField             matlab.ui.control.EditField
        eighthlayerPanel_2              matlab.ui.container.Panel
        Pixelsabove7thlayerEditField_2Label  matlab.ui.control.Label
        Pixelsabove7thlayerEditField_2  matlab.ui.control.EditField
        Pixelsbelow7thlayerEditFieldLabel  matlab.ui.control.Label
        Pixelsbelow7thlayerEditField    matlab.ui.control.EditField
        sixthlayerPanel_3               matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Topregionabove7thlayerEditFieldLabel  matlab.ui.control.Label
        Topregionabove7thlayerEditField_2  matlab.ui.control.EditField
        Bottomregionabove7thlayerEditFieldLabel  matlab.ui.control.Label
        Bottomregionabove7thlayerEditField_2  matlab.ui.control.EditField
        firstlayerPanel                 matlab.ui.container.Panel
        Pixelsbelow6thlayerEditFieldLabel  matlab.ui.control.Label
        Pixelsbelow6thlayerEditField    matlab.ui.control.EditField
        fourthLayerPanel                matlab.ui.container.Panel
        Pixelsabove1stlayerEditFieldLabel  matlab.ui.control.Label
        Pixelsabove1stlayerEditField    matlab.ui.control.EditField
        Pixelsbelow5thlayerEditFieldLabel  matlab.ui.control.Label
        Pixelsbelow5thlayerEditField    matlab.ui.control.EditField
        thirdlayerPanel                 matlab.ui.container.Panel
        TextArea_2                      matlab.ui.control.TextArea
        Topregionbelow7thlayerEditFieldLabel  matlab.ui.control.Label
        Topregionbelow7thlayerEditField  matlab.ui.control.EditField
        Bottomregionbelow7thlayerEditFieldLabel  matlab.ui.control.Label
        Bottomregionbelow7thlayerEditField  matlab.ui.control.EditField
        secondlayerPanel                matlab.ui.container.Panel
        Pixelsabove1stlayerEditField_2Label  matlab.ui.control.Label
        Pixelsabove1stlayerEditField_2  matlab.ui.control.EditField
        Pixelsbelow3rdlayerEditFieldLabel  matlab.ui.control.Label
        Pixelsbelow3rdlayerEditField    matlab.ui.control.EditField
        SaveparametersButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        params % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, previousParams)
            app.params = previousParams;            
            disp(previousParams);
%             9th
            app.Pixelsabove7thlayerEditField.Value = num2str(previousParams.p9thAboveBandWidth);
            app.SmoothnessEditField.Value =  num2str(previousParams.p9thSmoothParam);
%             8th
            app.Pixelsabove7thlayerEditField_2.Value = num2str(previousParams.p8thAboveBandWidth);
            app.Pixelsbelow7thlayerEditField.Value = num2str(previousParams.p8thBelowBandWidth);
%             6th
            app.Topregionabove7thlayerEditField_2.Value = num2str(previousParams.p6thAboveBandWidth);
            app.Bottomregionabove7thlayerEditField_2.Value = num2str(previousParams.p6thBelowBandWidth);
%           4h
            app.Pixelsabove1stlayerEditField.Value = num2str(previousParams.p4thAboveBandWidth2);
            app.Pixelsbelow5thlayerEditField.Value = num2str(previousParams.p4thBelowBandWidth2);
%             3rd)
            app.Topregionbelow7thlayerEditField.Value = num2str(previousParams.p3thAboveBandWidth);
            app.Bottomregionbelow7thlayerEditField.Value = num2str(previousParams.p3thBelowBandWidth);
%             2nd
            app.Pixelsabove1stlayerEditField_2.Value = num2str(previousParams.p2thAboveBandWidth);
            app.Pixelsbelow3rdlayerEditField.Value = num2str(previousParams.p2thBelowBandWidth);
%             1st
            app.Pixelsbelow6thlayerEditField.Value = num2str(previousParams.p1stBelowBandWidth);
            
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            global updatedParams;
            
            updatedParams = app.params;
      
            assignin('base','updatedParams',updatedParams);
            
            msgbox('Parametri salvati cu success!', 'Success','help');
        end

        % Value changed function: Pixelsabove7thlayerEditField
        function Pixelsabove7thlayerEditFieldValueChanged(app, event)
            value = app.Pixelsabove7thlayerEditField.Value;
            
            app.params.p9thAboveBandWidth = str2double(value);
        end

        % Value changed function: SmoothnessEditField
        function SmoothnessEditFieldValueChanged(app, event)
            value = app.SmoothnessEditField.Value;
                   
            app.params.p9thSmoothParam = str2double(value);
        end

        % Value changed function: Pixelsabove7thlayerEditField_2
        function Pixelsabove7thlayerEditField_2ValueChanged(app, event)
            value = app.Pixelsabove7thlayerEditField_2.Value;
                      
            app.params.p8thAboveBandWidth = str2double(value);
        end

        % Value changed function: Pixelsbelow7thlayerEditField
        function Pixelsbelow7thlayerEditFieldValueChanged(app, event)
            value = app.Pixelsbelow7thlayerEditField.Value;
            
            app.params.p8thBelowBandWidth = str2double(value);
        end

        % Value changed function: Topregionabove7thlayerEditField_2
        function Topregionabove7thlayerEditField_2ValueChanged(app, event)
            value = app.Topregionabove7thlayerEditField_2.Value;
            
            app.params.p6thAboveBandWidth = str2double(value);
        end

        % Value changed function: 
        % Bottomregionabove7thlayerEditField_2
        function Bottomregionabove7thlayerEditField_2ValueChanged(app, event)
            value = app.Bottomregionabove7thlayerEditField_2.Value;
            
            app.params.p6thBelowBandWidth = str2double(value);
        end

        % Value changed function: Pixelsabove1stlayerEditField
        function Pixelsabove1stlayerEditFieldValueChanged(app, event)
            value = app.Pixelsabove1stlayerEditField.Value;
            
            app.params.p4thAboveBandWidth2 = str2double(value);
        end

        % Value changed function: Pixelsbelow5thlayerEditField
        function Pixelsbelow5thlayerEditFieldValueChanged(app, event)
            value = app.Pixelsbelow5thlayerEditField.Value;
            
            app.params.p4thBelowBandWidth2 = str2double(value);
        end

        % Value changed function: Pixelsabove1stlayerEditField_2
        function Pixelsabove1stlayerEditField_2ValueChanged(app, event)
            value = app.Pixelsabove1stlayerEditField_2.Value;
            
            app.params.p2thAboveBandWidth = str2double(value);
        end

        % Value changed function: Pixelsbelow3rdlayerEditField
        function Pixelsbelow3rdlayerEditFieldValueChanged(app, event)
            value = app.Pixelsbelow3rdlayerEditField.Value;
            
            app.params.p2thBelowBandWidth = str2double(value);
        end

        % Value changed function: Topregionbelow7thlayerEditField
        function Topregionbelow7thlayerEditFieldValueChanged(app, event)
            value = app.Topregionbelow7thlayerEditField.Value;
            
            app.params.p3thAboveBandWidth = str2double(value);
        end

        % Value changed function: Bottomregionbelow7thlayerEditField
        function Bottomregionbelow7thlayerEditFieldValueChanged(app, event)
            value = app.Bottomregionbelow7thlayerEditField.Value;
            
            app.params.p3thBelowBandWidth = str2double(value);
        end

        % Value changed function: Pixelsbelow6thlayerEditField
        function Pixelsbelow6thlayerEditFieldValueChanged(app, event)
            value = app.Pixelsbelow6thlayerEditField.Value;
            
            app.params.p1stBelowBandWidth = str2double(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UpdatetheparametersaccordingtoyourneedsLabel = uilabel(app.UIFigure);
            app.UpdatetheparametersaccordingtoyourneedsLabel.Text = 'Update the parameters according to your needs:';
            app.NinthlayerPanel = uipanel(app.UIFigure);
            app.NinthlayerPanel.Title = '9th layer';
            app.Pixelsabove7thlayerEditFieldLabel = uilabel(app.NinthlayerPanel);
            app.Pixelsabove7thlayerEditFieldLabel.Text = 'Pixels above 7th layer:';
            app.Pixelsabove7thlayerEditField = uieditfield(app.NinthlayerPanel, 'text');
            app.Pixelsabove7thlayerEditField.ValueChangedFcn = createCallbackFcn(app, @Pixelsabove7thlayerEditFieldValueChanged, true);
            app.Pixelsabove7thlayerEditField.Value = '123';
            app.SmoothnessEditFieldLabel = uilabel(app.NinthlayerPanel);
            app.SmoothnessEditFieldLabel.Text = 'Smoothness';
            app.SmoothnessEditField = uieditfield(app.NinthlayerPanel, 'text');
            app.SmoothnessEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothnessEditFieldValueChanged, true);
            app.eighthlayerPanel_2 = uipanel(app.UIFigure);
            app.eighthlayerPanel_2.Title = '8th layer';
            app.Pixelsabove7thlayerEditField_2Label = uilabel(app.eighthlayerPanel_2);
            app.Pixelsabove7thlayerEditField_2Label.Text = 'Pixels above 7th layer';
            app.Pixelsabove7thlayerEditField_2 = uieditfield(app.eighthlayerPanel_2, 'text');
            app.Pixelsabove7thlayerEditField_2.ValueChangedFcn = createCallbackFcn(app, @Pixelsabove7thlayerEditField_2ValueChanged, true);
            app.Pixelsbelow7thlayerEditFieldLabel = uilabel(app.eighthlayerPanel_2);
            app.Pixelsbelow7thlayerEditFieldLabel.Text = 'Pixels below 7th layer:';
            app.Pixelsbelow7thlayerEditField = uieditfield(app.eighthlayerPanel_2, 'text');
            app.Pixelsbelow7thlayerEditField.ValueChangedFcn = createCallbackFcn(app, @Pixelsbelow7thlayerEditFieldValueChanged, true);
            app.sixthlayerPanel_3 = uipanel(app.UIFigure);
            app.sixthlayerPanel_3.Title = '6th layer';
            app.Label = uilabel(app.sixthlayerPanel_3);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.sixthlayerPanel_3);
            app.TextArea.Enable = 'off';
            app.TextArea.Value = {'(ROI determined by the difference between the two regions)'};
            app.Topregionabove7thlayerEditFieldLabel = uilabel(app.sixthlayerPanel_3);
            app.Topregionabove7thlayerEditFieldLabel.Text = 'Top region above 7th layer';
            app.Topregionabove7thlayerEditField_2 = uieditfield(app.sixthlayerPanel_3, 'text');
            app.Topregionabove7thlayerEditField_2.ValueChangedFcn = createCallbackFcn(app, @Topregionabove7thlayerEditField_2ValueChanged, true);
            app.Bottomregionabove7thlayerEditFieldLabel = uilabel(app.sixthlayerPanel_3);
            app.Bottomregionabove7thlayerEditFieldLabel.Text = 'Bottom region above 7th layer';
            app.Bottomregionabove7thlayerEditField_2 = uieditfield(app.sixthlayerPanel_3, 'text');
            app.Bottomregionabove7thlayerEditField_2.ValueChangedFcn = createCallbackFcn(app, @Bottomregionabove7thlayerEditField_2ValueChanged, true);
            app.firstlayerPanel = uipanel(app.UIFigure);
            app.firstlayerPanel.Title = '1st layer';
            app.Pixelsbelow6thlayerEditFieldLabel = uilabel(app.firstlayerPanel);
            app.Pixelsbelow6thlayerEditFieldLabel.Text = 'Pixels below 6th layer:';
            app.Pixelsbelow6thlayerEditField = uieditfield(app.firstlayerPanel, 'text');
            app.Pixelsbelow6thlayerEditField.ValueChangedFcn = createCallbackFcn(app, @Pixelsbelow6thlayerEditFieldValueChanged, true);
            app.fourthLayerPanel = uipanel(app.UIFigure);
            app.fourthLayerPanel.Title = '4th Layer';
            app.Pixelsabove1stlayerEditFieldLabel = uilabel(app.fourthLayerPanel);
            app.Pixelsabove1stlayerEditFieldLabel.Text = 'Pixels above 1st layer';
            app.Pixelsabove1stlayerEditField = uieditfield(app.fourthLayerPanel, 'text');
            app.Pixelsabove1stlayerEditField.ValueChangedFcn = createCallbackFcn(app, @Pixelsabove1stlayerEditFieldValueChanged, true);
            app.Pixelsbelow5thlayerEditFieldLabel = uilabel(app.fourthLayerPanel);
            app.Pixelsbelow5thlayerEditFieldLabel.Text = 'Pixels below 5th layer';
            app.Pixelsbelow5thlayerEditField = uieditfield(app.fourthLayerPanel, 'text');
            app.Pixelsbelow5thlayerEditField.ValueChangedFcn = createCallbackFcn(app, @Pixelsbelow5thlayerEditFieldValueChanged, true);
            app.thirdlayerPanel = uipanel(app.UIFigure);
            app.thirdlayerPanel.Title = '3rd layer';
            app.TextArea_2 = uitextarea(app.thirdlayerPanel);
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Value = {'(ROI determined by the difference between the two regions)'};
            app.Topregionbelow7thlayerEditFieldLabel = uilabel(app.thirdlayerPanel);
            app.Topregionbelow7thlayerEditFieldLabel.Text = 'Top region below 7th layer';
            app.Topregionbelow7thlayerEditField = uieditfield(app.thirdlayerPanel, 'text');
            app.Topregionbelow7thlayerEditField.ValueChangedFcn = createCallbackFcn(app, @Topregionbelow7thlayerEditFieldValueChanged, true);
            app.Bottomregionbelow7thlayerEditFieldLabel = uilabel(app.thirdlayerPanel);
            app.Bottomregionbelow7thlayerEditFieldLabel.Text = 'Bottom region below 7th layer';
            app.Bottomregionbelow7thlayerEditField = uieditfield(app.thirdlayerPanel, 'text');
            app.Bottomregionbelow7thlayerEditField.ValueChangedFcn = createCallbackFcn(app, @Bottomregionbelow7thlayerEditFieldValueChanged, true);
            app.secondlayerPanel = uipanel(app.UIFigure);
            app.secondlayerPanel.Title = '2nd layer';
            app.Pixelsabove1stlayerEditField_2Label = uilabel(app.secondlayerPanel);
            app.Pixelsabove1stlayerEditField_2Label.Text = 'Pixels above 1st layer';
            app.Pixelsabove1stlayerEditField_2 = uieditfield(app.secondlayerPanel, 'text');
            app.Pixelsabove1stlayerEditField_2.ValueChangedFcn = createCallbackFcn(app, @Pixelsabove1stlayerEditField_2ValueChanged, true);
            app.Pixelsbelow3rdlayerEditFieldLabel = uilabel(app.secondlayerPanel);
            app.Pixelsbelow3rdlayerEditFieldLabel.Text = 'Pixels below 3rd layer:';
            app.Pixelsbelow3rdlayerEditField = uieditfield(app.secondlayerPanel, 'text');
            app.Pixelsbelow3rdlayerEditField.ValueChangedFcn = createCallbackFcn(app, @Pixelsbelow3rdlayerEditFieldValueChanged, true);
            app.SaveparametersButton = uibutton(app.UIFigure, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = 'Save parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = updateParams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end